classdef cell_display_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        extract_cell_dataButton         matlab.ui.control.StateButton
        DefaltdelayedresponseEditField  matlab.ui.control.NumericEditField
        DefaltdelayedresponseEditFieldLabel  matlab.ui.control.Label
        Switch_2                        matlab.ui.control.Switch
        BinsizeSecEditField             matlab.ui.control.NumericEditField
        BinsizeSecEditFieldLabel        matlab.ui.control.Label
        ManualButton                    matlab.ui.control.Button
        PlotIRhistogramButton           matlab.ui.control.Button
        displaySTDCheckBox              matlab.ui.control.CheckBox
        comparebeforafterCheckBox       matlab.ui.control.CheckBox
        clustringappButton              matlab.ui.control.Button
        loadclustersfileButton          matlab.ui.control.Button
        loadanalizeddataButton          matlab.ui.control.Button
        cellnumberDropDown              matlab.ui.control.DropDown
        cellnumberDropDownLabel         matlab.ui.control.Label
        TimetodisplayButtonGroup        matlab.ui.container.ButtonGroup
        AfterCNOButton                  matlab.ui.control.RadioButton
        BeforCNOButton                  matlab.ui.control.RadioButton
        nextButton                      matlab.ui.control.Button
        minimalpvalueEditField          matlab.ui.control.NumericEditField
        minimalpvalueLabel              matlab.ui.control.Label
        saveallButton_2                 matlab.ui.control.Button
        DefalttimeresponseEditField     matlab.ui.control.NumericEditField
        DefalttimeresponseLabel         matlab.ui.control.Label
        analyzeallButton                matlab.ui.control.Button
        normalizedCheckBox              matlab.ui.control.CheckBox
        NDstodisplayListBox             matlab.ui.control.ListBox
        NDstodisplayListBoxLabel        matlab.ui.control.Label
        loadbeforeCNOButton             matlab.ui.control.Button
        loadafterCNOButton              matlab.ui.control.Button
        previosButton                   matlab.ui.control.Button
        loadpositionsfileButton         matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        PSTHIRTab                       matlab.ui.container.Tab
        saveoutputbybrainsegionCheckBox  matlab.ui.control.CheckBox
        SmoothdataCheckBox              matlab.ui.control.CheckBox
        BrainregionEditField            matlab.ui.control.EditField
        BrainregionLabel                matlab.ui.control.Label
        ResponsetypeEditField           matlab.ui.control.EditField
        ResponsetypeLabel               matlab.ui.control.Label
        ToSpinner                       matlab.ui.control.Spinner
        ToSpinnerLabel                  matlab.ui.control.Label
        FromSpinner                     matlab.ui.control.Spinner
        FromSpinnerLabel                matlab.ui.control.Label
        IRtodisplayButtonGroup          matlab.ui.container.ButtonGroup
        CostumeButton                   matlab.ui.control.RadioButton
        SustainedButton                 matlab.ui.control.RadioButton
        OFFButton_2                     matlab.ui.control.RadioButton
        ONButton_2                      matlab.ui.control.RadioButton
        ApllycostumeforallButton        matlab.ui.control.Button
        timeforintenstyresponseLabel    matlab.ui.control.Label
        UITable2                        matlab.ui.control.Table
        UIAxes7                         matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes3_3                       matlab.ui.control.UIAxes
        DriftandadaptationTab           matlab.ui.container.Tab
        UIAxes4_4                       matlab.ui.control.UIAxes
        UIAxes4_3                       matlab.ui.control.UIAxes
        UIAxes4_2                       matlab.ui.control.UIAxes
        AllrecordingTab                 matlab.ui.container.Tab
        Switch                          matlab.ui.control.ToggleSwitch
        binsizeSecSlider                matlab.ui.control.Slider
        binsizeSecSliderLabel           matlab.ui.control.Label
        InjectiontimeEditField          matlab.ui.control.EditField
        InjectiontimeEditFieldLabel     matlab.ui.control.Label
        loadspike_timesButton           matlab.ui.control.Button
        loadspike_clustersButton        matlab.ui.control.Button
        UIAxes6                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        %%parameters with defalt values that are changeable:
        intensity_units = [15.4, 14.9, 14.4, 13.9, 13.4, 12.9, 12.4, 11.4, 10.4, 9.4] %log photon*cm^-2*sec^-1 relevent for each intensity number
        sampling_rate = 40000 % sampling rate for full recording PSTH in HZ
        
        
        %% basic parameters of raw PSTH file (change if using a new type of csv file):
        cell_id_row = 1 %row of cell names
        intensty_id_row = 2 % row of intensity
        repetition_id_row = 3 % row of repetition
        data_row = 4 %row where raw PSTH data starts
        data_col = 2 %colomn where raw PSTH data start
        bin_size = 0.1 %of the raw PSTH in seconds (5 bins a sec)
        on_stimulus = 3 % time of on stimulus
        off_stimulus = 12.8 % time of off stimulus
        intensty_suffix_length = 2; % length of the suffix '_0' in the name of intensities in the csv file
        sus_window = 6;
        %% basic parameters of the mapping csv file (change if using a new type of csv file):
        mapping_id_column = 1; % column of cell id in raw mapping_file
        region_name_column = 5; % column of region_name in raw mapping_file
        
        
        %% variabels changeable by with callbacks in the app (if you change then change to relevant callback):
        active_times = 1% %the active part of the recording to show (befor=1/after CNO=2). defalt is befor CNO.
        minimal_p_value = 0.05;%minimal p.value for significance
        IR2display = 'ON';%Response tipe to display on the IR panel as chosen by user in IRtodisplayButtonGroup. default  on - if changed need to change the default  selection in IRtodisplayButtonGroup.
        %time in secondes for calculating the on, off and sustained
        %responses - for exampel if On stimulus is in 3 sec and initial
        %time response is 2 so the on response will be calculated form 3:5
        %sec of the PSTH:
        initial_response_time = 1.8;
        full_recording_binnig = 60; % binning for the full recording histogram in HZ - changed by user in binsizeHZSliderValueChanged
        compare = 0 % if to compare bsfore and after injection on the same plot
        
        
        %% variabels used by the app (do not change):
        raw_histogram %raw PSTH loaded from the csv file
        intensities % cell arrey of intesiteis in datafile
        intensities_active %intesiteis chosen by user to analize
        all_data % a variabel contaning all the analized data for each cell (befor and after CNO injection)
        cell_num %number of uniqe cells in csv data file
        id % line of the ids of the cells in csv data file
        display_std % if STD is to be displayed - true if the user checks the STD display box
        current_cell %cerrent cell chosen by user to display
        max_response % max pvalue of each cell
        intensty_data_matrix %matrix with data for current intensty
        baseline_vector% vector of basline for current intensty
        coustume_changed = 1% variebel for tracking if the IR response time was changed (relevant for the upadte_IR function)
        mapping_file% variabel for loding the csv file of the cell positions
        clusters % varibel contaning the clusters file
        file_name % name of first rawPSTH file loaded. used ass the experiment name for the clusters file and for the defalt saving name for the all data saving
        spike_clusters % file from kilosrot with the cluster of each spike in spike times. initialy zero to check if the file is loaded.
        spike_times % file from kilosort with all spike times
        
        delayed_time = 1; % time from the bigning of the response to the begening of the elevation
    end


    methods (Access = private)
        
        function fit_data = sigmoid_fit(app, cell_data, response_bins)
            %create intensty response curve for relevant response:
            %extract psth of relevant intensities and insert to IR
            
            %prealocate
            IR.mean  = zeros(1, length(app.intensities_active));
            IR.std  = zeros(1, length(app.intensities_active));
            intensity_unit_relevant = zeros(1, length(app.intensities_active));
            count = 0;
            
            %run the loop for each intensity
            for i = app.intensities_active
                count = count + 1;
                %extract psth
                intensity_num = str2double(cell2mat(i));
                psth = cell_data.intensities(intensity_num).psth.mean;
                
                %save IR and light intensty in log units for calculating fit
                intensity_unit_relevant(count) = app.intensity_units(intensity_num);
                IR.mean(count) = mean(psth(response_bins));
                IR.std(count) = std(psth(response_bins));
            end
            
            % normalize response to be non-negative
            fit_data.shift = min(IR.mean);
            IR_norm = IR.mean-repmat(fit_data.shift,1,length(IR.mean)); % add the minimum value to all IR so all value will be non negarive
            
            %run fit function with following parameters (changeable) - these
            %parameters where shown to work with our data
            
            x = intensity_unit_relevant'; %transpose parameters for fit function
            y = IR_norm';
            
            fo = fitoptions('Method','NonlinearLeastSquares',...
                'TolFun',1.0E-20,...
                'TolX',1.0E-20,...
                'Lower',[-1,min(x),-5],...
                'Upper',[2*max(y),max(x),5],...
                'StartPoint',[max(y),mean(x),0]);
            
            ft = fittype('Rmax*10^(n*x)/(10^(n*x)+10^(n*logK))','options',fo);
            [curve,gof]=fit(x,y,ft);
            
            %return the output data
            fit_data.IR = IR;
            fit_data.curve = curve;
            fit_data.gof = gof;
            fit_data.intensities = x;
            fit_data.times = response_bins*app.bin_size;
            
            
        end


        function results = pvalue(app, response_bins, response_type, intensity, baseline_matrix)
            %create vector of response time and compare with basline
            response_matrix = app.intensty_data_matrix(response_bins,:);
            response_vector = mean(response_matrix);
            baseline_vector = mean(baseline_matrix);
            permutations = 1000;
            [results.value, results.observeddifference, results.effectsize] = permutationTest(response_vector, baseline_vector, permutations);
            results.significant = results.value < app.minimal_p_value;
            results.effectsize = abs(results.effectsize);
            %check if it is the maximal pvalue of the cell
            if ~strcmp(response_type, "sustained")
                if results.effectsize > app.max_response.effectsize
                    app.max_response.effectsize = results.effectsize;
                    app.max_response.type = response_type;
                    app.max_response.intensity = intensity;
                    
                end
            end
        end


        function cell_display(app)
            %plot psth , repetition basline, stimulus time repetition, intensty basline, tabel, IR plot, brain position and response type:
            
            cell_data = app.all_data{app.active_times}.(app.current_cell); %data for relevant cell to display
            
            %display response type
            app.ResponsetypeEditField.Value = cell_data.type;
            
            %plot PSTH, repetition basline, and stimulus repetition and table for each intensity
            count = 0;
            h = cell(1,length(app.intensities_active)); %prealocate a cell arrey for all the legends to be ploted
            tabel = zeros(length(app.intensities_active), 6); %prealocate for the table data
            
            for i = app.intensities_active
                count = count + 1;
                
                %extract intensity data
                intensity_num = str2double(cell2mat(i));
                intensity_data = cell_data.intensities(intensity_num);
                
                %find repetitions
                repetition_time = length(intensity_data.psth.mean)*app.bin_size;
                repetitions = 1:length(intensity_data.repetition_baseline.mean);
                
                
                %plot with std error bars or without (user choice)
                psth = intensity_data.psth; %psth for relevant intensty
                time = ((((1:length(psth.mean)) - 1)*app.bin_size))-app.on_stimulus;
                if app.display_std
                    errorbar(app.UIAxes_3, time, psth.mean, psth.std)  % psth plot
                    errorbar(app.UIAxes4_3, repetitions, intensity_data.repetition_baseline.mean, intensity_data.repetition_baseline.std)  %repetition basline plot
                else
                    if app.SmoothdataCheckBox.Value
                        %bin psth
                        cut_end = floor(length(psth.mean)*app.bin_size)/app.bin_size;
                        binned_psth = mean(reshape(psth.mean(1:cut_end), 1/app.bin_size, []));
                        %interpulate PSTH
                        y = 1:length(binned_psth);
                        xq = 1:app.bin_size:length(binned_psth);
                        interpulated_psth = interpn(y, binned_psth, xq, 'linear');
                        plot(app.UIAxes_3, time(1:length(interpulated_psth)), interpulated_psth)  %plot psth
                    else
                        plot(app.UIAxes_3, time, psth.mean)  %plot psth
                    end
                    plot(app.UIAxes4_3, repetitions, intensity_data.repetition_baseline.mean)  %repetition basline plot
                end
                h{count} = num2str(app.intensity_units(intensity_num)); %fill legend variabele with the relevant intensity
                
                hold(app.UIAxes_3, "on");
                hold(app.UIAxes4_3, "on");
                
                %plot stimulus repetition
                plot(app.UIAxes4_2, repetitions, intensity_data.repetition_firing_rate)  %repetition stimulus time plot
                hold(app.UIAxes4_2, "on");
                
                %plot data in tabel
                tabel(count,1) = app.intensity_units(intensity_num);
                tabel(count,2) = intensity_data.peak;
                tabel(count,3) = intensity_data.latency;
                tabel(count,4) = intensity_data.on.value;
                tabel(count,5) = intensity_data.off.value;
                tabel(count,6) = intensity_data.sustained.value;


            end
            
            %plot all intensity baslines
            if app.display_std
                errorbar(app.UIAxes4_4, cell_data.fit_data.on.intensities, cell_data.baseline_vector.mean, cell_data.baseline_vector.std, '-o')
            else
                plot(app.UIAxes4_4, cell_data.fit_data.on.intensities, cell_data.baseline_vector.mean, '-o')
            end
            legend(app.UIAxes4_4, 'baseline')
            
            
            %if compere option is on plot the befor and after of one
            %intensity in the same plot
            
            if app.compare
                %find the active time and plot the oposite
                if app.active_times ==1
                    not_active_time = 2;
                else
                    not_active_time = 1;
                end
                cell_data2 = app.all_data{not_active_time}.(app.current_cell);%cell data of not chosen time to plot
                intensity_data2 = cell_data2.intensities(intensity_num);
                psth = intensity_data2.psth; %psth for relevant intensty
                hold(app.UIAxes4_4) %hold on the intensity baslines plot
                if app.display_std
                    errorbar(app.UIAxes_3, time, psth.mean, psth.std)  % psth plot
                    errorbar(app.UIAxes4_3, repetitions, intensity_data2.repetition_baseline.mean, intensity_data2.repetition_baseline.std)  %repetition basline plot
                    %plot all intensity baslines
                    errorbar(app.UIAxes4_4, cell_data2.fit_data.on.intensities, cell_data2.baseline_vector.mean, cell_data2.baseline_vector.std, '-o')
                else
                    if app.SmoothdataCheckBox.Value
                        %bin psth
                        cut_end = floor(length(psth.mean)*app.bin_size)/app.bin_size;
                        binned_psth = mean(reshape(psth.mean(1:cut_end), 1/app.bin_size, []));
                        %interpulate PSTH
                        y = 1:length(binned_psth);
                        xq = 1:app.bin_size:length(binned_psth);
                        interpulated_psth = interpn(y, binned_psth, xq, 'pchip');
                        plot(app.UIAxes_3, time(1:length(interpulated_psth)), interpulated_psth)  %plot psth
                    else
                        plot(app.UIAxes_3, time, psth.mean)  %plot psth
                    end
                    plot(app.UIAxes4_3, repetitions, intensity_data2.repetition_baseline.mean)  %repetition basline plot
                    %plot all intensity baslines
                    plot(app.UIAxes4_4, cell_data2.fit_data.on.intensities, cell_data2.baseline_vector.mean, '-o')
                end
                %plot stimulus repetition
                plot(app.UIAxes4_2, repetitions, intensity_data2.repetition_firing_rate)
                
            end
            app.UITable2.Data = tabel; %present data data in the tabel
            
            
            %format psth plot
            xline(app.UIAxes_3, 0, '-', 'on');
            xline(app.UIAxes_3, app.off_stimulus - app.on_stimulus, '-', 'off');
            legend(app.UIAxes_3, h(1:count))
            xlim(app.UIAxes_3, [-app.on_stimulus (repetition_time-app.on_stimulus)])
            upadte_IR(app)  %update IR if costume is selected (and plot the red lines for chosing the costumed selected aria on the PSTH)
            hold(app.UIAxes_3, "off");
            
            %format repetition plots
            legend(app.UIAxes4_3, h(1:count))
            xlim(app.UIAxes4_3, [repetitions(1) repetitions(end)])
            legend(app.UIAxes4_2, h(1:count))
            xlim(app.UIAxes4_2, [repetitions(1) repetitions(end)])
            
            %plot difrent legendes if before and after if compared
            if app.compare
                legendes = {'before' , 'after'};
                if app.active_times == 2
                    legendes = {'after' , 'before'};
                end
                legend(app.UIAxes_3, legendes)
                legend(app.UIAxes4_3, legendes)
                legend(app.UIAxes4_2, legendes)
                legend(app.UIAxes4_4, legendes)
            end
            
            %take off all legend boxes bloking the plot
            legend(app.UIAxes_3 ,'boxoff')
            legend(app.UIAxes4_3 ,'boxoff')
            legend(app.UIAxes4_2 ,'boxoff')
            legend(app.UIAxes4_4 ,'boxoff')
            %hold off for the next cell to be plotted
            hold(app.UIAxes4_3, "off");
            hold(app.UIAxes4_2, "off");
            hold(app.UIAxes4_4, "off");
            
            
            %present the cell position
            if isfield(cell_data, 'position')
                app.BrainregionEditField.Value = cell_data.position{1};
            else
                app.BrainregionEditField.Value = ('no mapping file');
            end
            
            %plot Clusters PSTH
            if ~isempty(app.clusters)
                clust_num = cell_data.cluster;
                clust = app.clusters;
                errorbar(app.UIAxes7, time, clust.mean(clust_num, :), clust.ste(clust_num, :))
                title_text = ['Cluster ' num2str(clust_num) ' PSTH'];
                title(app.UIAxes7, title_text)
                xlim(app.UIAxes7, [time(1) time(end)])
            end
            
            IRplot(app)%plot IR curve
            
            %plot all recording psth if the files are uploaded
            if ~isempty(app.spike_clusters)
                display_all_recording(app)
            end
        end
        
        
        function IRplot(app)
            %plot the IR curve + fit
            
            cell_data = app.all_data{app.active_times}.(app.current_cell).fit_data.(lower(app.IR2display));
            if app.display_std
                errorbar(app.UIAxes3_3, cell_data.intensities, cell_data.IR.mean, cell_data.IR.std,'o', 'DisplayName','intensity response')
            else
                plot(app.UIAxes3_3, cell_data.intensities, cell_data.IR.mean,'o', 'DisplayName','intensity response')
            end
            hold(app.UIAxes3_3, "on");
            %plot the fit (works directly only if ploting in a normal
            %figure and not in uiaxes so i need to evaluate the data and
            %plot manualy
            x = (cell_data.intensities(1)-1):0.001:(cell_data.intensities(end)+1); %create vector of the x values of the fit plot
            y = feval(cell_data.curve,x) + cell_data.shift;
            plot(app.UIAxes3_3, x, y,'m', 'DisplayName','sigmoid fit')
            subtitle(app.UIAxes3_3, ['R^2 = ',num2str(cell_data.gof.rsquare, 3)]);
            xlim(app.UIAxes3_3, [x(1) x(end)])
            % legend(app.UIAxes3_3, 'location','best') % the legend may hide some of the
            %plot but you can turn it on if you want.
            %legend(app.UIAxes3_3 ,'boxoff') %
            
            max_IR = max(abs(cell_data.IR.mean));%maximum value for limiting the y of axes
            ylim(app.UIAxes3_3, [-1.5*max_IR  1.5*max_IR])
            hold(app.UIAxes3_3, "off")
            
        end


        function upadte_IR(app)
            %updateds IR if costume is selected
            if strcmp(app.IR2display, "Costume")
                if app.coustume_changed
                    response_bins = round(((app.FromSpinner.Value:app.bin_size:app.ToSpinner.Value) + app.on_stimulus)/app.bin_size);
                    app.all_data{app.active_times}.(app.current_cell).fit_data.costume = sigmoid_fit(app, app.all_data{app.active_times}.(app.current_cell), response_bins);
                end
                %plot lines on the PSTH to see that edges of the costumed IR
                xline(app.UIAxes_3, app.FromSpinner.Value, '--r', 'IR start')
                xline(app.UIAxes_3, app.ToSpinner.Value, '--r', 'IR end')
            end
        end
        
        function clust = calculate_new_mean(~, clust)
            %calculate the new mean of the cluster after adding to the cluster.matrix
            % all the relevant cells from the current experament.
            matrix = cell2mat(clust.matrix(:, 4:end));
            for i = 1:clust.gm.NumVariables
                %find all cells of each cluster
                indices = (matrix(:, 1) == i);
                clust.num_of_cells(i) = sum(indices);
                clust.mean(i, :) = mean(matrix(indices, 2:end), 1);
                clust.std(i, :) = std(matrix(indices, 2:end), 0, 1);
            end
        end
        
        function display_all_recording(app)
            %display PSTH of the full recording (in the all recording app)
            %find spike indices for all/currenst cell
            if strcmp(app.Switch.Value, 'Cells activity')
                %find spike times for current cell
                spike_indices = app.spike_times(find(app.spike_clusters == str2double(app.cellnumberDropDown.Value)));
            elseif strcmp(app.Switch.Value, 'Total activity')
                spike_indices = app.spike_times;
            end
            
            %find bin size, and numbers
            full_recording_bin_size = app.sampling_rate*app.full_recording_binnig;
            recording_end = app.spike_times(end); %end of recording in samples
            spike_train = zeros(recording_end, 1);
            spike_train(spike_indices) = 1;
            
            bin_num = floor(double(recording_end)/full_recording_bin_size); %number of bins
            %cut the end of the spike train that is not a full bin
            spike_train = spike_train(1:bin_num*full_recording_bin_size);
            
            %bin spike train
            psth = (sum(reshape(spike_train, full_recording_bin_size, [])))/app.full_recording_binnig;
            time = (1:bin_num)*app.full_recording_binnig;
            
            %plot psth
            plot(app.UIAxes6, time, psth); %plot PSTH without the last bin that is partly empty
            
            %plot line in injection time
            xline(app.UIAxes6, str2double(app.InjectiontimeEditField.Value), '-', 'injection');
            xlim(app.UIAxes6, [time(1) time(end)] )
            ylim(app.UIAxes6, [min(psth) max(psth) ] )
        end


        function plot_IR_histogram(app) %function for ploting the IR statistics (mean and histogram) of all the cells in the experiment
            figure
            a = app.all_data{1};
            cell_names = fieldnames(a);
            response_types = fieldnames(a.(cell_names{1}).fit_data);
            R2 = zeros(2, length(cell_names));
            response_nums = length(response_types);
            for j = 1:response_nums %iterate over the response times for R^2 (on off sustained and costume)
                for i = 1:length(cell_names) % iterate over the cells
                    before_data = app.all_data{1}.(cell_names{i}).fit_data.(response_types{j});
                    R2(1, i) = before_data.gof.rsquare;
                    after_data = app.all_data{2}.(cell_names{i}).fit_data.(response_types{j});
                    R2(2, i) = after_data.gof.rsquare;
                end
                
                %plot R^2 means
                subplot(2, response_nums ,j)  %the ploting is not in the app because this function is not used much and I dont want to make the app heavier
                X = categorical({'Before','After'});
                X = reordercats(X,{'Before','After'});
                bar(X, mean(R2 ,2))
                title([response_types{j} ' R^2 mean'])
                
                %plot R^2 histograms
                subplot(2, response_nums, (j+response_nums))
                hold on
                histogram(R2(1, :), (0:0.1:1)); %plot befor
                histogram(R2(2, :), (0:0.1:1));%plot after
                legend('Befor', 'After')
                ylim([0 15]);
                xlim([0 1]);
                xticks(0:0.2:1);
                title([response_types{j} ' R^2 histogram'])
                hold off
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: loadbeforeCNOButton
        function loadbeforeCNOButtonPushed(app, event)
            %When the user clicks the load data button, a window should open to enable the user to select a file.
            app.baseline_vector = [];
            app.intensty_data_matrix = [];
            app.cell_num = [];
            app.mapping_file = [];
            app.all_data = [];
            app.raw_histogram = [];
            
            [file, path] = uigetfile('*.csv');%When the user clicks the load data button, a window should open to enable the user to select a file.
            datafile = fullfile(path, file); %save path
            app.file_name = file(1:(end-4));
            [~,~,raw] = xlsread(datafile);
            
            cd(path)
            app.raw_histogram{1} = raw(:,app.data_col:end);
            
            %find the number of cells in the csv file
            app.id{1} = [app.raw_histogram{1}{app.cell_id_row,:}]; %create arrey of id of all cells
            app.cell_num = unique(app.id{1});
            %find relevant intensties in csv file
            intensties_id = app.raw_histogram{1}(app.intensty_id_row,:);
            app.intensities_active= unique(intensties_id);
            %set relevant intensties in box list
            all_intensties = zeros(1, length(app.intensities_active)); %preaclocate for all intensteis
            for i = 1:length(app.intensities_active)
                intenstiy = (app.intensities_active{i});
                all_intensties(i) = str2double(intenstiy(1:end-app.intensty_suffix_length)); %take of the _0 suffix of the intensity name
            end
            all_intensties = sort(all_intensties, 'descend');  %sort the intensiteis
            app.intensities_active = arrayfun(@num2str, all_intensties, 'UniformOutput', 0); %put intensteis in the arrey of active intensties (activate all relevant intenseties)
            app.NDstodisplayListBox.Items = app.intensities_active; %update the select box with the new intensitis
            
            %selesct all relevat intensties automaticaly
            app.NDstodisplayListBox.Value = app.NDstodisplayListBox.Items;
            %updat the cellnumberDropDown acording to the number of cell,
            % choose the first cell and save it an app.curren_cell
            app.cellnumberDropDown.Items = string(app.cell_num);
            app.cellnumberDropDown.Value = app.cellnumberDropDown.Items(1);
            app.current_cell = ['cell_' app.cellnumberDropDown.Value];
            
            
        end

        % Value changed function: DefalttimeresponseEditField
        function DefalttimeresponseEditFieldValueChanged(app, event)
            value = app.DefalttimeresponseEditField.Value;
            app.initial_response_time = value;
        end

        % Value changed function: minimalpvalueEditField
        function minimalpvalueEditFieldValueChanged(app, event)
            value = app.minimalpvalueEditField.Value;
            app.minimal_p_value = value;
        end

        % Callback function
        function minimalR2valueEditFieldValueChanged(app, event)
            value = app.minimalR2valueEditField.Value;
            app.minimal_R_value = value;
        end

        % Value changed function: NDstodisplayListBox
        function NDstodisplayListBoxValueChanged(app, event)
            value = app.NDstodisplayListBox.Value;
            app.intensities_active = value;
            if app.compare
                app.intensities_active = {app.intensities_active}; %if multiselect is off then value is not a cell and dose not work with cell2mat in cell_display
            end
            
            cell_display(app)
        end

        % Button pushed function: analyzeallButton
        function analyzeallButtonPushed(app, event)
            %go over the befor and after CNO and anlize all the parameters
            %for later display
            
            clust = app.clusters;
            baseline.mean = zeros(1, length(app.intensities_active)); %pre alocate a basline vector for all intensties active
            baseline.std = zeros(1, length(app.intensities_active));
            
            for a = 1:length(app.raw_histogram)
                
                % go over each cell and calculate the parameters for the relevant intensities
                for i = app.cell_num
                    %create cell name for later analizing
                    cell_name = ['cell_' num2str(i)];
                    %iterate over the intensities selected
                    relevant_cell_data = app.raw_histogram{a}(:,app.id{a}==i);
                    %initilize max response variabel
                    app.max_response.effectsize = 0;
                    count = 0;
                    for j = app.intensities_active
                        count = count + 1;
                        %extract position of relevant intensty
                        intensity_num = str2double(cell2mat(j)); %extract intensty as number for later anlizing
                        intensity_indices = contains(relevant_cell_data(app.intensty_id_row,:), [char(j) '_']);
                        %exract data for relevant intensty
                        relevant_intensty_data = relevant_cell_data(app.data_row:end,intensity_indices);
                        %convert cell to matrix
                        app.intensty_data_matrix = cell2mat(relevant_intensty_data);
                        
                        
                        %% calculate outputs:
                        %calculate statistics:
                        %create baseline matrix of the pre-on stimulus time
                        baseline_bins = app.on_stimulus/app.bin_size;
                        baseline_matrix = app.intensty_data_matrix(1:baseline_bins,:);
                        
                        %create vector of baseline bins
                        app.baseline_vector = baseline_matrix(:);
                        %create matrix of stimulus time
                        stimulus_time = app.off_stimulus - app.on_stimulus;
                        stimulus_bins =  stimulus_time/app.bin_size + baseline_bins;
                        stimulus_matrix = app.intensty_data_matrix(baseline_bins:stimulus_bins,:);
                        
                        %create vector of bin for on, off, and sustained response
                        on_bins = round(((app.on_stimulus + app.delayed_time):app.bin_size:(app.on_stimulus+app.initial_response_time))/app.bin_size) ;
                        off_bins = round(((app.off_stimulus + app.delayed_time + 0.2):app.bin_size:(app.off_stimulus+app.initial_response_time+0.2))/app.bin_size) ;
                        sustained_bins = round((app.off_stimulus-app.sus_window)/app.bin_size:app.off_stimulus/app.bin_size) ;
                        
                        %clculate p value using the Wilcoxon rank sum test
                        %and ditermin if it is significant for on, off and
                        %sustained response.
                        struct.on = pvalue(app, on_bins, "on", intensity_num, baseline_matrix);
                        struct.off = pvalue(app, off_bins, "off", intensity_num, baseline_matrix);
                        struct.sustained = pvalue(app, sustained_bins, "sustained", intensity_num, baseline_matrix);
                        struct.intensty_data = app.intensty_data_matrix;
                        
                        
                        %calculate change in firing rate over first secondes of all repetitions (repetition basline)
                        struct.repetition_baseline.mean = mean(baseline_matrix);
                        struct.repetition_baseline.std = std(baseline_matrix);
                        
                        %calculate baseline + std for each intensity
                        struct.intensty_baseline.mean = mean(baseline_matrix, 'all');
                        struct.intensty_baseline.std = std(baseline_matrix, 0, 'all');
                        
                        baseline.mean(count) = struct.intensty_baseline.mean;
                        baseline.std(count) = struct.intensty_baseline.std;


                        %normalize to baseline
                        if app.normalizedCheckBox.Value
                            app.intensty_data_matrix = app.intensty_data_matrix - struct.intensty_baseline.mean;
                        end
                        
                        %caculate firing rate of stimulus time of all repetitions
                        struct.repetition_firing_rate = mean(stimulus_matrix);
                        
                        %caclulate PSTH:
                        struct.psth.mean = mean(app.intensty_data_matrix, 2);
                        struct.psth.std = std(app.intensty_data_matrix, 0, 2);
                        
                        %calculate value of peek and latency to peek
                        [struct.peak, latency] = max(struct.psth.mean);
                        struct.latency = latency*app.bin_size - app.on_stimulus;
                        
                        %save all to output structure
                        cell_data.intensities(intensity_num) = struct;
                        
                    end
                    
                    %save intensty baseline
                    cell_data.baseline_vector = baseline;
                    
                    %calculate IR response for on, off an sustained
                    cell_data.fit_data.on = sigmoid_fit(app, cell_data, on_bins);
                    cell_data.fit_data.off = sigmoid_fit(app, cell_data, off_bins);
                    cell_data.fit_data.sustained = sigmoid_fit(app, cell_data, sustained_bins);
                    
                    %determine intensty of max response
                    cell_data.max = app.max_response;
                    %determine response tipe based on best intensity (intensity
                    %with max response) and pvalue of on, off and sustained for
                    %that intensty
                    best_intensity =cell_data.max.intensity;
                    best_intensity_pvalues =  cell_data.intensities(best_intensity);
                    if best_intensity_pvalues.on.significant
                        cell_data.type = 'ON';
                        if best_intensity_pvalues.off.significant
                            cell_data.type = 'ON-OFF';
                        end
                    elseif best_intensity_pvalues.off.significant
                        cell_data.type = 'OFF';
                    else
                        cell_data.type = 'Not responding';
                    end
                    
                    if best_intensity_pvalues.sustained.significant
                        cell_data.type = [cell_data.type ' Sustained'];
                    end
                    
                    %add the position of cell from mapping file
                    if ~isempty(app.mapping_file)
                        mapping_cell_num = find([app.mapping_file{2:end ,app.mapping_id_column}] == i) + 1; %find the position of the current cell in the mapping file
                        if mapping_cell_num
                            cell_data.position = app.mapping_file(mapping_cell_num, app.region_name_column);
                            cell_data.cordinates.x = app.mapping_file(mapping_cell_num, strcmp(app.mapping_file(1,:), 'x'));
                            cell_data.cordinates.y = app.mapping_file(mapping_cell_num, strcmp(app.mapping_file(1,:), 'y'));
                            cell_data.cordinates.z = app.mapping_file(mapping_cell_num, strcmp(app.mapping_file(1,:), 'z'));
                            cell_data.region_acronym = app.mapping_file(mapping_cell_num, strcmp(app.mapping_file(1,:), 'region_acronym'));
                            
                            
                        end
                    end
                    
                    
                    %% cluster cell in the GMM models from clusters file
                    %create a vector the best intensitiys psth of the PCs from the clustering
                    if ~isempty(app.clusters)
                        %bin the PSTH
                        cut_end = floor(length(best_intensity_pvalues.psth.mean)*app.bin_size)/app.bin_size;
                        binned_psth = mean(reshape(best_intensity_pvalues.psth.mean(1:cut_end), app.clusters.clustering_binsize/app.bin_size, []));
                        %check if normalized to boundes and if so normlize the
                        %psth
                        if clust.normalize2bound
                            psth_norm = binned_psth/max(abs(binned_psth));
                        else
                            psth_norm = binned_psth;
                        end
                        new_response_bins = (clust.response_bins(1):clust.clustering_binsize:clust.response_bins(end))*1/0.5;
                        PC_vector = (psth_norm(new_response_bins))*(clust.coeff(:, 1:clust.gm.NumVariables));
                        cell_data.cluster = cluster(clust.gm,PC_vector); %cluster the cell
                        
                        %add a new row to cluster for the new cell
                        new_ids{1} = app.file_name;
                        new_ids{2} = a;
                        new_ids{3} = cell_name;
                        new_ids{4} = cell_data.cluster;
                        new_row = [new_ids num2cell(best_intensity_pvalues.psth.mean')];
                        %add the row to cluster matrix
                        clust.matrix = [clust.matrix; new_row];
                        
                        %insert the cell to the relevant cluster
                        cluster_name = ['cluster_' num2str(cell_data.cluster)];
                        clust.cluster_cells.(cluster_name){end} = cell_data;
                        clust.cluster_cells.(cluster_name){end}.cell_name = cell_name;
                        clust.cluster_cells.(cluster_name){end}.exp_name = [app.file_name '_' num2str(a)];
                        clust.cluster_cells.(cluster_name){end + 1} = []; %add a cell for next iteration
                    end
                    
                    %save cell to app.data
                    app.all_data{a}.(cell_name) = cell_data;
                    
                end
            end
            %update the new clsuter mean and STD
            if ~isempty(app.clusters)
                clust = calculate_new_mean(app, clust);
                app.clusters = clust;
            end
            cell_display(app)
        end

        % Button pushed function: saveallButton_2
        function saveallButton_2Pushed(app, event)
            %save all data and clusters
            [file,path] = uiputfile([app.file_name '.mat']);
            filename = fullfile(path, file);
            all_data = app.all_data;
            save(filename, 'all_data', '-mat')
            
            
            %save by cell positions:
            if ~strcmp(app.BrainregionEditField.Value, 'no mapping file') && app.saveoutputbybrainsegionCheckBox.Value
                position = cell(length(app.cell_num), 1);
                cell_names = fieldnames(app.all_data{1});
                for i = 1:length(cell_names)
                    position{i} = all_data{1}.(cell_names{i}).position{1};
                end
                [uniqe_positions,~ , indices]= unique(position);
                for i = 1:length(uniqe_positions)
                    for j = 1:length(app.all_data)
                        all_data = rmfield(app.all_data{j}, cell_names(indices ~= i));
                        temp_filename = [filename(1:end-4) '_' uniqe_positions{i} '.mat'];
                        save(temp_filename, 'all_data', '-mat')
                    end
                end
            end
            
            
            %save cluster files if it is uplouded
            if ~isempty(app.clusters)
                [file,path] = uiputfile('clusters.mat');
                filename = fullfile(path, file);
                clusters = app.clusters;
                save(filename, 'clusters', '-mat')
            end
        end

        % Value changed function: cellnumberDropDown
        function cellnumberDropDownValueChanged(app, event)
            value = app.cellnumberDropDown.Value;
            app.current_cell = ['cell_' value];
            cell_display(app)
        end

        % Value changed function: displaySTDCheckBox
        function displaySTDCheckBoxValueChanged(app, event)
            value = app.displaySTDCheckBox.Value;
            app.display_std = value;
            cell_display(app)
        end

        % Selection changed function: IRtodisplayButtonGroup
        function IRtodisplayButtonGroupSelectionChanged(app, event)
            selectedButton = app.IRtodisplayButtonGroup.SelectedObject;
            app.IR2display = selectedButton.Text;
            cell_display(app)
        end

        % Button pushed function: nextButton
        function nextButtonPushed(app, event)
            %find current cell position in cells list
            cell_position = find(ismember(app.cellnumberDropDown.Items, app.cellnumberDropDown.Value));
            %check if it is the last cell and if not move to next
            if cell_position < length(app.cellnumberDropDown.Items)
                app.cellnumberDropDown.Value = app.cellnumberDropDown.Items(cell_position + 1);
                app.current_cell = ['cell_' app.cellnumberDropDown.Value];
                cell_display(app)%update the display
            end
        end

        % Button pushed function: previosButton
        function previosButtonPushed(app, event)
            %find current cell position in cells list
            cell_position = find(ismember(app.cellnumberDropDown.Items, app.cellnumberDropDown.Value));
            %check if it is the first cell and if not move to next
            if cell_position > 1
                app.cellnumberDropDown.Value = app.cellnumberDropDown.Items(cell_position - 1);
                app.current_cell = ['cell_' app.cellnumberDropDown.Value];
                cell_display(app)%update the display
            end
            
        end

        % Button pushed function: loadafterCNOButton
        function loadafterCNOButtonPushed(app, event)
            [file, path] = uigetfile('*.csv');%When the user clicks the load data button, a window should open to enable the user to select a file.
            datafile = fullfile(path, file); %save path
            [~,~,raw] = xlsread(datafile);
            app.raw_histogram{2} = raw(:,app.data_col:end);
            %find the number of cells in the csv file to deal with cases
            %that some cells are detected only beafore or after the CNO. in
            %that case the app will analyzeonly the cells that are in both
            %files. (it is posibell to analyzeall the cells of one of the files if you load only that one)
            app.id{2} = [app.raw_histogram{2}{app.cell_id_row,:}]; %create arrey of id of all cells
            app.cell_num = app.cell_num(ismember(app.cell_num, unique(app.id{2}))); %keep only cells in both files
            %update the cells in the cell number Drop Down
            app.cellnumberDropDown.Items = string(app.cell_num);
            app.cellnumberDropDown.Value = app.cellnumberDropDown.Items(1);
            app.current_cell = ['cell_' app.cellnumberDropDown.Value];
            
            
            %find relevant intensties in csv file
            intensties_id = app.raw_histogram{2}(app.intensty_id_row,:);
            old_intensities = app.intensities_active;
            app.intensities_active= unique(intensties_id);
            %set relevant intensties in box list
            all_intensties = zeros(1, length(app.intensities_active)); %preaclocate for all intensteis
            for i = 1:length(app.intensities_active)
                intenstiy = (app.intensities_active{i});
                all_intensties(i) = str2double(intenstiy(1:end-app.intensty_suffix_length)); %take of the _0 suffix of the intensity name
            end
            all_intensties = sort(all_intensties, 'descend');  %sort the intensiteis
            app.intensities_active = arrayfun(@num2str, all_intensties, 'UniformOutput', 0); %put intensteis in the arrey of active intensties (activate all relevant intenseties)
            app.intensities_active = app.intensities_active(ismember(app.intensities_active, old_intensities));
            app.NDstodisplayListBox.Items = app.intensities_active; %update the select box with the new intensitis
            
            %selesct all relevat intensties automaticaly
            app.NDstodisplayListBox.Value = app.NDstodisplayListBox.Items;
            
        end

        % Callback function
        function timeofrecordingListBoxValueChanged(app, event)
            value = app.timeofrecordingListBox.Value;
            app.active_times = value; %the active part of the recording to show (befor/after CNO)
            display(app)
        end

        % Selection changed function: TimetodisplayButtonGroup
        function TimetodisplayButtonGroupSelectionChanged(app, event)
            value = app.TimetodisplayButtonGroup.SelectedObject;
            if strcmp(value.Text, 'Befor CNO' )%check what button is sulected and asign 1 for befor CNO and 2 for after CNO
                app.active_times = 1;
            elseif strcmp(value.Text, 'After CNO' )
                app.active_times = 2;
            end
            cell_display(app)
        end

        % Value changed function: FromSpinner
        function FromSpinnerValueChanged(app, event)
            %make shure value is in psth window
            if app.FromSpinner.Value <  - app.on_stimulus
                app.FromSpinner.Value = - app.on_stimulus;
            end
            if app.ToSpinner.Value <  (app.FromSpinner.Value + app.bin_size)
                app.FromSpinner.Value = app.ToSpinner.Value - app.bin_size;
            end
            app.coustume_changed = 1;
            cell_display(app);
        end

        % Value changed function: ToSpinner
        function ToSpinnerValueChanged(app, event)
            %make shure IR window is positive
            if app.ToSpinner.Value <  (app.FromSpinner.Value + app.bin_size)
                app.ToSpinner.Value = app.FromSpinner.Value + app.bin_size;
            end
            app.coustume_changed = 1;
            cell_display(app);
        end

        % Button pushed function: ApllycostumeforallButton
        function ApllycostumeforallButtonPushed(app, event)
            app.coustume_changed = 0;
            %save to app.all_data all the costumed IR
            count = 0;
            for a = app.all_data
                count = count + 1;
                for i = app.cell_num
                    cell_name = ['cell_' num2str(i)];
                    response_bins = round(((app.FromSpinner.Value:app.bin_size:app.ToSpinner.Value) + app.on_stimulus)/app.bin_size);
                    a{1}.(cell_name).fit_data.costume = sigmoid_fit(app, a{1}.(cell_name), response_bins);
                end
                app.all_data{count} = a{1};
            end
        end

        % Button pushed function: loadpositionsfileButton
        function loadpositionsfileButtonPushed(app, event)
            [file, path] = uigetfile('*.csv');%When the user clicks the load position button, a window should open to enable the user to select a file.
            datafile = fullfile(path, file); %save path
            [~,~,raw] = xlsread(datafile);
            app.mapping_file = raw;
            
        end

        % Button pushed function: loadanalizeddataButton
        function loadanalizeddataButtonPushed(app, event)
            [file, path] = uigetfile('*.mat');%When the user clicks the load analize button, a window should open to enable the user to select a file.
            datafile = fullfile(path, file); %save path
            app.file_name = file; %save name of the experiment
            data = load(datafile);
            cd(path)
            
            if isa(data.all_data, 'struct')
                app.all_data{1,1} = data.all_data;
            else
                app.all_data = data.all_data;
            end
            %update the cell cellnumberDropDown according to the analized
            %file in app.all_data
            cells = (fieldnames(app.all_data{1}))';
            app.cellnumberDropDown.Items = cellfun(@(x) x(6:end),cells,'UniformOutput',false);
            app.cell_num = cellfun(@str2double, app.cellnumberDropDown.Items);
            %set the current cell to be first
            app.cellnumberDropDown.Value = app.cellnumberDropDown.Items(1);
            app.current_cell = ['cell_' app.cellnumberDropDown.Value];
            %update the intensiteis acording to the analyzed data:
            all_intensties = find(~cellfun(@isempty, {app.all_data{1}.(cells{1}).intensities.on}));
            all_intensties = sort(all_intensties, 'descend');  %sort the intensiteis
            app.intensities_active = arrayfun(@num2str, all_intensties, 'UniformOutput', 0); %put intensteis in the arrey of active intensties (activate all relevant intenseties)
            app.NDstodisplayListBox.Items = app.intensities_active; %update the select box with the new intensitis
            
            %selesct all relevat intensties automaticaly
            app.NDstodisplayListBox.Value = app.NDstodisplayListBox.Items;
            
            %display all
            cell_display(app)
        end

        % Button pushed function: loadclustersfileButton
        function loadclustersfileButtonPushed(app, event)
            [file, path] = uigetfile('*.mat');%When the user clicks the load cluster button, a window should open to enable the user to select a file.
            datafile = fullfile(path, file); %save path
            app.clusters = load(datafile);
            app.clusters = app.clusters.clusters;
        end

        % Button pushed function: loadspike_clustersButton
        function loadspike_clustersButtonPushed(app, event)
            [file, path] = uigetfile('*.npy');
            datafile = fullfile(path, file);
            app.spike_clusters = readNPY(datafile);
        end

        % Button pushed function: loadspike_timesButton
        function loadspike_timesButtonPushed(app, event)
            [file, path] = uigetfile('*.npy');
            datafile = fullfile(path, file);
            app.spike_times = readNPY(datafile);
            display_all_recording(app)
        end

        % Value changed function: InjectiontimeEditField
        function InjectiontimeEditFieldValueChanged(app, event)
            display_all_recording(app)
        end

        % Value changed function: binsizeSecSlider
        function binsizeSecSliderValueChanged(app, event)
            value = app.binsizeSecSlider.Value;
            app.full_recording_binnig = round(value);
            display_all_recording(app)
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            app.Switch.Value; %display all cells or one cell at a time
            display_all_recording(app)
        end

        % Button pushed function: clustringappButton
        function clustringappButtonPushed(app, event)
            clustring
        end

        % Value changed function: comparebeforafterCheckBox
        function comparebeforafterCheckBoxValueChanged(app, event)
            value = app.comparebeforafterCheckBox.Value;
            %if compered is checked make it imposibl to chose more then one
            %intensty and start comparing the highes intensty
            if value
                app.compare = 1;
                app.NDstodisplayListBox.Value = app.NDstodisplayListBox.Items(end);
                app.NDstodisplayListBox.Multiselect = 0;
                app.intensities_active = {app.NDstodisplayListBox.Value};
                cell_display(app)
            else
                app.compare = 0;
                app.NDstodisplayListBox.Multiselect = 1;
                cell_display(app)
            end
        end

        % Button pushed function: PlotIRhistogramButton
        function PlotIRhistogramButtonPushed(app, event)
            plot_IR_histogram(app)
        end

        % Button pushed function: ManualButton
        function ManualButtonPushed(app, event)
            winopen('USER_MANUAL.pdf')
        end

        % Value changed function: BinsizeSecEditField
        function BinsizeSecEditFieldValueChanged(app, event)
            app.bin_size = app.BinsizeSecEditField.Value;
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            value = app.Switch_2.Value;
            if strcmp(value, '7 intensties')
                app.intensity_units = [15.4, 14.4, 13.4, 12.4, 11.4, 10.4, 9.4];
            else
                app.intensity_units = [15.4, 14.9, 14.4, 13.9, 13.4, 12.9, 12.4, 11.4, 10.4, 9.4];
            end
            
        end

        % Value changed function: SmoothdataCheckBox
        function SmoothdataCheckBoxValueChanged(app, event)
            cell_display(app)
            
        end

        % Value changed function: DefaltdelayedresponseEditField
        function DefaltdelayedresponseEditFieldValueChanged(app, event)
            value = app.DefaltdelayedresponseEditField.Value;
            app.delayed_time = value;
        end

        % Value changed function: extract_cell_dataButton
        function extract_cell_dataButtonValueChanged(app, event)
            value = app.extract_cell_dataButton.Value;
            extract_cell_data(app.file_name, app.cellnumberDropDown.Value, 1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PSTHIRTab = uitab(app.TabGroup);
            app.PSTHIRTab.Title = 'PSTH+ IR';
            app.UIAxes3_3 = uiaxes(app.PSTHIRTab);
            title(app.UIAxes3_3, {'Intesty response curve'; ''})
            xlabel(app.UIAxes3_3, 'intensty (log photons)')
            ylabel(app.UIAxes3_3, 'firing rate (spike/sec)')
            zlabel(app.UIAxes3_3, 'Z')
            app.UIAxes3_3.XTick = [9.4 10.4 11.4 12.4 13.4 14.4 15.4];
            app.UIAxes3_3.XTickLabel = {'9.4'; '10.4'; '11.4'; '12.4'; '13.4'; '14.4'; '15.4'};
            app.UIAxes_3 = uiaxes(app.PSTHIRTab);
            title(app.UIAxes_3, 'PSTH')
            xlabel(app.UIAxes_3, 'time (sec)')
            ylabel(app.UIAxes_3, 'firing rate (spikes/sec)')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_3.XTick = [-3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17];
            app.UIAxes_3.XTickLabel = {'-3'; '-2'; '-1'; '0'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'; '11'; '12'; '13'; '14'; '15'; '16'; '17'};
            app.UIAxes7 = uiaxes(app.PSTHIRTab);
            title(app.UIAxes7, 'Cluster PSTH')
            xlabel(app.UIAxes7, 'Time (sec)')
            ylabel(app.UIAxes7, 'Firing rate (Hz)')
            zlabel(app.UIAxes7, 'Z')
            app.UITable2 = uitable(app.PSTHIRTab);
            app.UITable2.ColumnName = {'Intensity'; 'peek'; 'latency to peek (sec)'; 'Pvalue ON'; 'Pvalue OFF'; 'Pvalue Sustained'};
            app.UITable2.RowName = {};
            app.UITable2.ColumnEditable = true;
            app.timeforintenstyresponseLabel = uilabel(app.PSTHIRTab);
            app.timeforintenstyresponseLabel.Text = 'time for intensty response';
            app.ApllycostumeforallButton = uibutton(app.PSTHIRTab, 'push');
            app.ApllycostumeforallButton.ButtonPushedFcn = createCallbackFcn(app, @ApllycostumeforallButtonPushed, true);
            app.ApllycostumeforallButton.Text = 'Aplly costume for all';
            app.IRtodisplayButtonGroup = uibuttongroup(app.PSTHIRTab);
            app.IRtodisplayButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @IRtodisplayButtonGroupSelectionChanged, true);
            app.IRtodisplayButtonGroup.Title = 'IR to display';
            app.ONButton_2 = uiradiobutton(app.IRtodisplayButtonGroup);
            app.ONButton_2.Text = 'ON';
            app.ONButton_2.Value = true;
            app.OFFButton_2 = uiradiobutton(app.IRtodisplayButtonGroup);
            app.OFFButton_2.Text = 'OFF';
            app.SustainedButton = uiradiobutton(app.IRtodisplayButtonGroup);
            app.SustainedButton.Text = 'Sustained';
            app.CostumeButton = uiradiobutton(app.IRtodisplayButtonGroup);
            app.CostumeButton.Text = 'Costume';
            app.FromSpinnerLabel = uilabel(app.PSTHIRTab);
            app.FromSpinnerLabel.Text = 'From:';
            app.FromSpinner = uispinner(app.PSTHIRTab);
            app.FromSpinner.Step = 0.2;
            app.FromSpinner.ValueChangedFcn = createCallbackFcn(app, @FromSpinnerValueChanged, true);
            app.ToSpinnerLabel = uilabel(app.PSTHIRTab);
            app.ToSpinnerLabel.Text = 'To:';
            app.ToSpinner = uispinner(app.PSTHIRTab);
            app.ToSpinner.Step = 0.2;
            app.ToSpinner.ValueChangedFcn = createCallbackFcn(app, @ToSpinnerValueChanged, true);
            app.ToSpinner.Value = 1;
            app.ResponsetypeLabel = uilabel(app.PSTHIRTab);
            app.ResponsetypeLabel.Text = 'Response type:';
            app.ResponsetypeEditField = uieditfield(app.PSTHIRTab, 'text');
            app.BrainregionLabel = uilabel(app.PSTHIRTab);
            app.BrainregionLabel.Text = 'Brain region:';
            app.BrainregionEditField = uieditfield(app.PSTHIRTab, 'text');
            app.SmoothdataCheckBox = uicheckbox(app.PSTHIRTab);
            app.SmoothdataCheckBox.ValueChangedFcn = createCallbackFcn(app, @SmoothdataCheckBoxValueChanged, true);
            app.SmoothdataCheckBox.Text = 'Smoothdata';
            app.saveoutputbybrainsegionCheckBox = uicheckbox(app.PSTHIRTab);
            app.saveoutputbybrainsegionCheckBox.Text = 'save output by brain segion';
            app.saveoutputbybrainsegionCheckBox.Value = true;
            app.DriftandadaptationTab = uitab(app.TabGroup);
            app.DriftandadaptationTab.Title = 'Drift and adaptation';
            app.UIAxes4_2 = uiaxes(app.DriftandadaptationTab);
            title(app.UIAxes4_2, 'Stimulus firing rate/repetiton ')
            xlabel(app.UIAxes4_2, 'Repetition number')
            ylabel(app.UIAxes4_2, 'Firirng rate')
            zlabel(app.UIAxes4_2, 'Z')
            app.UIAxes4_3 = uiaxes(app.DriftandadaptationTab);
            title(app.UIAxes4_3, 'Baseline/repetitoin ')
            xlabel(app.UIAxes4_3, 'Repetition number')
            ylabel(app.UIAxes4_3, 'Firing rate (Hz)')
            zlabel(app.UIAxes4_3, 'Z')
            app.UIAxes4_4 = uiaxes(app.DriftandadaptationTab);
            title(app.UIAxes4_4, 'Baseline/intensity ')
            xlabel(app.UIAxes4_4, 'intensity (log photons/cm^2*sec)')
            ylabel(app.UIAxes4_4, 'Firing rate')
            zlabel(app.UIAxes4_4, 'Z')
            app.AllrecordingTab = uitab(app.TabGroup);
            app.AllrecordingTab.Title = 'All recording';
            app.UIAxes6 = uiaxes(app.AllrecordingTab);
            title(app.UIAxes6, 'Full experiment firing rate')
            xlabel(app.UIAxes6, 'time (sec)')
            ylabel(app.UIAxes6, 'firing rate (Hz)')
            zlabel(app.UIAxes6, 'Z')
            app.loadspike_clustersButton = uibutton(app.AllrecordingTab, 'push');
            app.loadspike_clustersButton.ButtonPushedFcn = createCallbackFcn(app, @loadspike_clustersButtonPushed, true);
            app.loadspike_clustersButton.Icon = 'load-icon-3.jpg';
            app.loadspike_clustersButton.Text = 'load spike_clusters';
            app.loadspike_timesButton = uibutton(app.AllrecordingTab, 'push');
            app.loadspike_timesButton.ButtonPushedFcn = createCallbackFcn(app, @loadspike_timesButtonPushed, true);
            app.loadspike_timesButton.Icon = 'load-icon-3.jpg';
            app.loadspike_timesButton.Text = 'load spike_times';
            app.InjectiontimeEditFieldLabel = uilabel(app.AllrecordingTab);
            app.InjectiontimeEditFieldLabel.Text = 'Injection time:';
            app.InjectiontimeEditField = uieditfield(app.AllrecordingTab, 'text');
            app.InjectiontimeEditField.ValueChangedFcn = createCallbackFcn(app, @InjectiontimeEditFieldValueChanged, true);
            app.InjectiontimeEditField.Value = '2960';
            app.binsizeSecSliderLabel = uilabel(app.AllrecordingTab);
            app.binsizeSecSliderLabel.Text = 'bin size (Sec)';
            app.binsizeSecSlider = uislider(app.AllrecordingTab);
            app.binsizeSecSlider.Limits = [1 600];
            app.binsizeSecSlider.MajorTicks = [10 60 110 160 210 260 310 360 410 460 510 560];
            app.binsizeSecSlider.ValueChangedFcn = createCallbackFcn(app, @binsizeSecSliderValueChanged, true);
            app.binsizeSecSlider.Value = 300;
            app.Switch = uiswitch(app.AllrecordingTab, 'toggle');
            app.Switch.Items = {'Cells activity', 'Total activity'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Cells activity';
            app.loadpositionsfileButton = uibutton(app.UIFigure, 'push');
            app.loadpositionsfileButton.ButtonPushedFcn = createCallbackFcn(app, @loadpositionsfileButtonPushed, true);
            app.loadpositionsfileButton.Icon = 'load-icon-3.jpg';
            app.loadpositionsfileButton.Text = 'load positions file';
            app.previosButton = uibutton(app.UIFigure, 'push');
            app.previosButton.ButtonPushedFcn = createCallbackFcn(app, @previosButtonPushed, true);
            app.previosButton.Icon = 'back.jpg';
            app.previosButton.Text = 'previos';
            app.loadafterCNOButton = uibutton(app.UIFigure, 'push');
            app.loadafterCNOButton.ButtonPushedFcn = createCallbackFcn(app, @loadafterCNOButtonPushed, true);
            app.loadafterCNOButton.Icon = 'load-icon-3.jpg';
            app.loadafterCNOButton.Text = 'load after CNO';
            app.loadbeforeCNOButton = uibutton(app.UIFigure, 'push');
            app.loadbeforeCNOButton.ButtonPushedFcn = createCallbackFcn(app, @loadbeforeCNOButtonPushed, true);
            app.loadbeforeCNOButton.Icon = 'load-icon-3.jpg';
            app.loadbeforeCNOButton.Text = 'load before CNO';
            app.NDstodisplayListBoxLabel = uilabel(app.UIFigure);
            app.NDstodisplayListBoxLabel.Text = 'NDs to display';
            app.NDstodisplayListBox = uilistbox(app.UIFigure);
            app.NDstodisplayListBox.Items = {'10', '9', '8', '7', '6', '5', '4', '3', '2', '1'};
            app.NDstodisplayListBox.Multiselect = 'on';
            app.NDstodisplayListBox.ValueChangedFcn = createCallbackFcn(app, @NDstodisplayListBoxValueChanged, true);
            app.NDstodisplayListBox.Value = {'8'};
            app.normalizedCheckBox = uicheckbox(app.UIFigure);
            app.normalizedCheckBox.Text = 'normalized';
            app.normalizedCheckBox.Value = true;
            app.analyzeallButton = uibutton(app.UIFigure, 'push');
            app.analyzeallButton.ButtonPushedFcn = createCallbackFcn(app, @analyzeallButtonPushed, true);
            app.analyzeallButton.Icon = '69-691530_analysis-flat-icon-png-transparent-png.png';
            app.analyzeallButton.Text = 'analyze all';
            app.DefalttimeresponseLabel = uilabel(app.UIFigure);
            app.DefalttimeresponseLabel.Text = 'Defalt time response:';
            app.DefalttimeresponseEditField = uieditfield(app.UIFigure, 'numeric');
            app.DefalttimeresponseEditField.ValueChangedFcn = createCallbackFcn(app, @DefalttimeresponseEditFieldValueChanged, true);
            app.DefalttimeresponseEditField.Value = 1.8;
            app.saveallButton_2 = uibutton(app.UIFigure, 'push');
            app.saveallButton_2.ButtonPushedFcn = createCallbackFcn(app, @saveallButton_2Pushed, true);
            app.saveallButton_2.Icon = 'save.png';
            app.saveallButton_2.Text = 'save all';
            app.minimalpvalueLabel = uilabel(app.UIFigure);
            app.minimalpvalueLabel.Text = 'minimal p* value:';
            app.minimalpvalueEditField = uieditfield(app.UIFigure, 'numeric');
            app.minimalpvalueEditField.ValueChangedFcn = createCallbackFcn(app, @minimalpvalueEditFieldValueChanged, true);
            app.minimalpvalueEditField.Value = 0.05;
            app.nextButton = uibutton(app.UIFigure, 'push');
            app.nextButton.ButtonPushedFcn = createCallbackFcn(app, @nextButtonPushed, true);
            app.nextButton.Icon = 'next.jpeg';
            app.nextButton.Text = 'next';
            app.TimetodisplayButtonGroup = uibuttongroup(app.UIFigure);
            app.TimetodisplayButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TimetodisplayButtonGroupSelectionChanged, true);
            app.TimetodisplayButtonGroup.Title = 'Time to display';
            app.BeforCNOButton = uiradiobutton(app.TimetodisplayButtonGroup);
            app.BeforCNOButton.Text = 'Befor CNO';
            app.BeforCNOButton.Value = true;
            app.AfterCNOButton = uiradiobutton(app.TimetodisplayButtonGroup);
            app.AfterCNOButton.Text = 'After CNO';
            app.cellnumberDropDownLabel = uilabel(app.UIFigure);
            app.cellnumberDropDownLabel.Text = 'cell number';
            app.cellnumberDropDown = uidropdown(app.UIFigure);
            app.cellnumberDropDown.Items = {'1', '2', '3', '4'};
            app.cellnumberDropDown.ValueChangedFcn = createCallbackFcn(app, @cellnumberDropDownValueChanged, true);
            app.cellnumberDropDown.Value = '1';
            app.loadanalizeddataButton = uibutton(app.UIFigure, 'push');
            app.loadanalizeddataButton.ButtonPushedFcn = createCallbackFcn(app, @loadanalizeddataButtonPushed, true);
            app.loadanalizeddataButton.Icon = 'load-icon-3.jpg';
            app.loadanalizeddataButton.Text = 'load analized data';
            app.loadclustersfileButton = uibutton(app.UIFigure, 'push');
            app.loadclustersfileButton.ButtonPushedFcn = createCallbackFcn(app, @loadclustersfileButtonPushed, true);
            app.loadclustersfileButton.Icon = 'load-icon-3.jpg';
            app.loadclustersfileButton.Text = 'load clusters file';
            app.clustringappButton = uibutton(app.UIFigure, 'push');
            app.clustringappButton.ButtonPushedFcn = createCallbackFcn(app, @clustringappButtonPushed, true);
            app.clustringappButton.Icon = 'clusters.png';
            app.clustringappButton.Text = 'clustring app';
            app.comparebeforafterCheckBox = uicheckbox(app.UIFigure);
            app.comparebeforafterCheckBox.ValueChangedFcn = createCallbackFcn(app, @comparebeforafterCheckBoxValueChanged, true);
            app.comparebeforafterCheckBox.Text = 'compare befor/after';
            app.displaySTDCheckBox = uicheckbox(app.UIFigure);
            app.displaySTDCheckBox.ValueChangedFcn = createCallbackFcn(app, @displaySTDCheckBoxValueChanged, true);
            app.displaySTDCheckBox.Text = 'display STD';
            app.PlotIRhistogramButton = uibutton(app.UIFigure, 'push');
            app.PlotIRhistogramButton.ButtonPushedFcn = createCallbackFcn(app, @PlotIRhistogramButtonPushed, true);
            app.PlotIRhistogramButton.Icon = 'plot.jpeg';
            app.PlotIRhistogramButton.Text = 'Plot IR histogram';
            app.ManualButton = uibutton(app.UIFigure, 'push');
            app.ManualButton.ButtonPushedFcn = createCallbackFcn(app, @ManualButtonPushed, true);
            app.ManualButton.Icon = 'manual.png';
            app.ManualButton.Text = 'Manual';
            app.BinsizeSecEditFieldLabel = uilabel(app.UIFigure);
            app.BinsizeSecEditFieldLabel.Text = 'Bin size (Sec)';
            app.BinsizeSecEditField = uieditfield(app.UIFigure, 'numeric');
            app.BinsizeSecEditField.ValueChangedFcn = createCallbackFcn(app, @BinsizeSecEditFieldValueChanged, true);
            app.BinsizeSecEditField.Value = 0.1;
            app.Switch_2 = uiswitch(app.UIFigure, 'slider');
            app.Switch_2.Items = {'10 intensties', '7 intensties'};
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Switch_2.Value = '10 intensties';
            app.DefaltdelayedresponseEditFieldLabel = uilabel(app.UIFigure);
            app.DefaltdelayedresponseEditFieldLabel.Text = 'Defalt delayed response:';
            app.DefaltdelayedresponseEditField = uieditfield(app.UIFigure, 'numeric');
            app.DefaltdelayedresponseEditField.ValueChangedFcn = createCallbackFcn(app, @DefaltdelayedresponseEditFieldValueChanged, true);
            app.DefaltdelayedresponseEditField.Value = 1;
            app.extract_cell_dataButton = uibutton(app.UIFigure, 'state');
            app.extract_cell_dataButton.ValueChangedFcn = createCallbackFcn(app, @extract_cell_dataButtonValueChanged, true);
            app.extract_cell_dataButton.Text = 'extract_cell_data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cell_display_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end