classdef clustring2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        LoaddatafilesButton             matlab.ui.control.Button
        normalizeCheckBox               matlab.ui.control.CheckBox
        TabGroup                        matlab.ui.container.TabGroup
        GMMTab                          matlab.ui.container.Tab
        maxclustersEditFieldLabel       matlab.ui.control.Label
        maxclustersEditField            matlab.ui.control.NumericEditField
        ButtonGroup                     matlab.ui.container.ButtonGroup
        SheredButton                    matlab.ui.control.RadioButton
        UnsheredButton                  matlab.ui.control.RadioButton
        PreclusterButton                matlab.ui.control.Button
        numberofclustersEditFieldLabel  matlab.ui.control.Label
        numberofclustersEditField       matlab.ui.control.NumericEditField
        ClusterButton                   matlab.ui.control.Button
        ButtonGroup_2                   matlab.ui.container.ButtonGroup
        DiagonalButton                  matlab.ui.control.RadioButton
        FullButton                      matlab.ui.control.RadioButton
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        DBSCANTab                       matlab.ui.container.Tab
        minptsLabel                     matlab.ui.control.Label
        minptsEditField                 matlab.ui.control.NumericEditField
        epsilonEditFieldLabel           matlab.ui.control.Label
        epsilonEditField                matlab.ui.control.NumericEditField
        NumberofPCsLabel                matlab.ui.control.Label
        NumberofPCsEditField            matlab.ui.control.NumericEditField
        PlotkdistanceButton_3           matlab.ui.control.Button
        ClusterButton_3                 matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
        saveButton                      matlab.ui.control.Button
        celldisplayappButton            matlab.ui.control.Button
        ManualButton                    matlab.ui.control.Button
        SmoothdataCheckBox              matlab.ui.control.CheckBox
        ClusteringbinsizesecEditFieldLabel  matlab.ui.control.Label
        ClusteringbinsizesecEditField   matlab.ui.control.NumericEditField
        NDforclusteringEditFieldLabel   matlab.ui.control.Label
        NDforclusteringEditField        matlab.ui.control.NumericEditField
        UsebestNDCheckBox               matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        %%
        %changeable parameters befor for loading the file:
        response_bins = 2:15 % bins of the time of response taken for the clustering
        on = 3; %on response in sec
        bin_size = 0.1; %in sec
        off = 12.8; %off response in sec
        min_variance = 95; % The minimal varience (of the PSTH data) expressed by the PC taken for the clustering 

        
        %% 
        %defalt variabels changeable in the app (if you change the defalt
        %change the defalt value in the disign view):
        covariance = 1 % parameter for GMm clustering. 1 is diagonal (defalt) 2 is full
        SharedCovariance = 1 % parameter for GMm clustering. 1 is shered (defalt) 2 is unshered


        %%
        % variabels used by the app (do not change):
        all_files % a stucture contaning all files loaded to the app 
        psth_tabel %  matrix whith PSTHs of all the cells In the data files (befor and after injection are treated as difrent cells).
        psth_tabel_norm %psth tabel after the normalization to bound step. will contain the PSTH matrix also if the normilize option is not used.
        coeff %principal component coefficients created by the PCA anlisis and saved in order to add more cells to the GMM in the cell display app
        % Matrix of PSTHs of all cells (only the relevant response time - the bins in response_bins)  
        % expressed by the PCs accounted for 95% of the varience:
        pc_matrix 
        cluster_num % number of clusters calculated
        gm % And arrey of all optional gmdistribution model object from the tuning of the GMm 
        gmBest % gm of the best parameters chosen by the user
        clusters % variable holding all the clusters data
        idx % index of the cluster of each cell
        file % the name of the files loaded
        datafile % name of datafiles loaded
        clustering_type % type of clustreing (GMM/DBSCAN)
        
        binned_psth % the psth for pca after bining to 1 bin for a sec
        clusters_figure % figure saving all clusters
    end
    
    
    methods (Access = private)
        
        function Create_pc_matrix(app) %run pca on the data
            [app.coeff,score,~,~,explained] = pca(app.psth_tabel_norm(:,app.response_bins));
            % Find the number of components required to explain at least 95% variability.
            idx1 = find(cumsum(explained)>app.min_variance,1);
            app.pc_matrix = score(:, 1:idx1); %relevant component matrix for clustring
            
            app.NumberofPCsEditField.Value = idx1; %display the PC number for estemating minpts for DBSCAN
            app.minptsEditField.Value = idx1 + 1; %change the minpts difalt value to one plus the number of dimensions of the input data
        end
        
        function plot_clusters(app)
            %% plot the mean+STD PSTH off all the clusters (in a new figure besauce the amount of clusters might change - and to use shadedErrorBar that is not sutibael fot UIAXES)
            app.clusters_figure = figure;
            hold on;
            %prealocate for the PSTH parameters
            times = size(app.psth_tabel ,2); %size of PATH bins
            app.clusters.mean = zeros(app.cluster_num, times);
            app.clusters.std = app.clusters.mean;
            app.clusters.ste = app.clusters.std;
            app.clusters.num_of_cells = zeros(1, app.cluster_num); %number of cells in each cluster
            %create a variable for the time (x axes)
            x = ((((1:times)-1)*app.bin_size)-app.on);
            for i = 1:app.cluster_num
                %find all cells of each cluster
                indices = (app.idx == i);
                %add the values to the app.cluster variable
                app.clusters.num_of_cells(i) = sum(indices);
                app.clusters.mean(i, :) = mean(app.psth_tabel(indices, :), 1);
                app.clusters.std(i, :) = std(app.psth_tabel(indices, :), 0, 1);
                app.clusters.ste(i, :) = app.clusters.std(i, :)/sqrt(length(indices));
                %plot each cluster in a new subplot
                subplot(2, ceil(app.cluster_num/2),i) 
                hold on
                %plot mean +- std ass shaded error bar
                if app.SmoothdataCheckBox.Value
                        %bin psth
                        cut_end = floor(length(app.clusters.mean(i ,:))*app.bin_size)/app.bin_size;
                        bin_psth = mean(reshape(app.clusters.mean(i , 1:cut_end), 1/app.bin_size, []));
                        %interpulate PSTH
                        y = 1:length(bin_psth);
                        xq = 1:app.bin_size:length(bin_psth);
                        interpulated_psth = interpn(y, bin_psth, xq, 'pchip');
                        shadedErrorBar(x(1:length(interpulated_psth)), interpulated_psth, app.clusters.ste(i, 1:length(interpulated_psth)), 'lineprops', '-r')
                else
                    shadedErrorBar(x, app.clusters.mean(i ,:), app.clusters.ste(i, :), 'lineprops', '-r')
                end
                title (['cluster ' num2str(i)])
                subtitle(['number of cells = '  num2str(app.clusters.num_of_cells(i))])
                xlabel('Time (sec)')
                ylabel('Firing rate (Hz)')
                xlim([x(1) x(end)])
                xline(0 , '-' , 'on')
                xline(app.off-app.on , '-' , 'off');
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoaddatafilesButton
        function LoaddatafilesButtonPushed(app, event)
           %% load saved data
            [app.file, path] = uigetfile('*.mat','MultiSelect','on');%When the user clicks the load psetion button, a window should open to enable the user to select a file. 
            app.datafile = fullfile(path, app.file); %save path
            file_numes = length(app.datafile);
            app.all_files = cell(1, file_numes);
            for i = 1:file_numes
                app.all_files{1, i} = load(app.datafile{1,i});
                app.all_files{1, i} = app.all_files{1, i};
            end 
            
            %prealocate and axtract a psth tabel from all files
            row = 0;
            for w = 1:length(app.all_files)
                for a = 1:numel(app.all_files{1, w}.all_data)
                    cell_names = fieldnames(app.all_files{1, w}.all_data);
                    %extract the PSTH for each cell from the best
                    %responding intensty as determend in the cell display
                    %app (by the lowest pvalue).
                    for i = 1:numel(cell_names)
                        row = row + 1;
                        data = app.all_files{1, w}.all_data.(cell_names{i});
                        if app.UsebestNDCheckBox.Value
                            app.psth_tabel(row, :) = (data.intensities(data.max.intensity).psth.mean)'; %complicated to get size out of loop so i did it with no prealocating
                        else
                            app.psth_tabel(row, :) = (data.intensities(app.NDforclusteringEditField.Value).psth.mean)'; %complicated to get size out of loop so i did it with no prealocating
                        end    
                        %normalize to bounds (if normelized check box is
                        %checked. normalizing to bounds is devideing all the PSTH
                        % by the max abs(value) which makes the bounds of the PSTH between 1 and -1 
                        %It gives more weight to the PSTH waveform and less
                        %less weight to the firing rate change magnitude. 
                        binsize = app.bin_zise/app.ClusteringbinsizesecEditField.Value; %bin size in sec
                        cut_end = floor(length(app.psth_tabel(row, :))*binsize)/binsize;
                        app.binned_psth(row, :) = mean(reshape(app.psth_tabel(row, 1:cut_end), 1/binsize, []));
                        
                        if app.normalizeCheckBox.Value
                            app.psth_tabel_norm(row, :) = app.binned_psth(row, :)/max(abs(app.binned_psth(row, :)));
                        else
                            app.psth_tabel_norm(row, :) =  app.binned_psth(row, :);
                        end
                        
                    end
                    
                    
                end
            end
            %app.psth_tabel = app.binned_psth;
            Create_pc_matrix(app);
            
        end

        % Button pushed function: PreclusterButton
        function PreclusterButtonPushed(app, event)
            %% gausian tuning - check the best parmeters for gausain model mixture clustring
            %set parameters:
            %Suppose k is the number of desired components or clusters, and Sigmais the covariance structure for all components. 
            % Follow these steps to tune a GMM:
            %1. Choose a (k,Sigma) pair, and then fit a GMM using the chosen parameter specification and the entire data set.
            %2. Estimate the AIC and BIC.
            %3. Repeat steps 1 and 2 until you exhaust all (k, Sigma) pairs of interest.
            % Choose the fitted GMM that balances low AIC with simplicity.
            
            k = 1:app.maxclustersEditField.Value;
            nK = numel(k);
            Sigma = {'diagonal','full'};
            nSigma = numel(Sigma);
            SharedCovariance = {true,false};
            %SCtext = {'true','false'};
            nSC = numel(SharedCovariance);
            RegularizationValue = 0.01;
            options = statset('MaxIter',100000);
            
            %Fit the GMMs using all parameter combination. Compute the AIC
            %and BIC for each fit.
            % Preallocation
            app.gm = cell(nK,nSigma,nSC);
            aic = zeros(nK,nSigma,nSC);
            bic = zeros(nK,nSigma,nSC);
            converged = false(nK,nSigma,nSC);
            
            % Fit all models
            for m = 1:nSC
                for j = 1:nSigma
                    for i = 1:nK
                        app.gm{i,j,m} = fitgmdist(app.pc_matrix,k(i),...
                            'CovarianceType',Sigma{j},...
                            'SharedCovariance',SharedCovariance{m},...
                            'RegularizationValue',RegularizationValue,...
                            'Options',options);
                        aic(i,j,m) = app.gm{i,j,m}.AIC;
                        bic(i,j,m) = app.gm{i,j,m}.BIC;
                        converged(i,j,m) = app.gm{i,j,m}.Converged;
                    end
                end
            end
            
            
            %gm is a cell array containing all of the fitted gmdistribution model objects. All of the fitting instances converged.
            %Plot separate bar charts to compare the AIC and BIC among all fits. Group the bars by k.
            bar(app.UIAxes, reshape(aic,nK,nSigma*nSC));
            title(app.UIAxes, 'AIC For Various $k$ and $\Sigma$ Choices','Interpreter','latex');
            xlabel(app.UIAxes,'$k$','Interpreter','Latex');
            ylabel(app.UIAxes,'AIC');
            %ylim(app.UIAxes, [0 1.5*max(reshape(aic,nK,nSigma*nSC), [], "all")])
            legend(app.UIAxes,{'Diagonal-shared','Full-shared','Diagonal-unshared',...
              'Full-unshared'}); 
            
            
            bar(app.UIAxes_2,reshape(bic,nK,nSigma*nSC));
            title(app.UIAxes_2,'BIC For Various $k$ and $\Sigma$ Choices','Interpreter','latex');
            xlabel(app.UIAxes_2,'$k$','Interpreter','Latex');
            ylabel(app.UIAxes_2,'BIC');
            %ylim(app.UIAxes_2, [0 1.5*max(reshape(bic,nK,nSigma*nSC), [], "all")])
            legend(app.UIAxes_2,{'Diagonal-shared','Full-shared','Diagonal-unshared',...
                'Full-unshared'});
            
            %plot AIC + BIC
            bar(app.UIAxes_3, reshape((aic+bic),nK,nSigma*nSC));
            title(app.UIAxes_3, 'AIC + BIC For Various $k$ and $\Sigma$ Choices','Interpreter','latex');
            xlabel(app.UIAxes_3, '$k$','Interpreter','Latex');
            ylabel(app.UIAxes_3, 'AIC + BIC');
            %ylim(app.UIAxes_3, [0 1.5*max(reshape((aic+bic),nK,nSigma*nSC), [], "all")])
            legend(app.UIAxes_3, {'Diagonal-shared','Full-shared','Diagonal-unshared',...
                'Full-unshared'});
        end

        % Button pushed function: ClusterButton
        function ClusterButtonPushed(app, event)
            %% cluster using the best fitting model
            app.cluster_num = app.numberofclustersEditField.Value;
            app.gmBest = app.gm{app.cluster_num,app.covariance,app.SharedCovariance};%best parameters to cluster with
            app.idx = cluster(app.gmBest,app.pc_matrix); %index of all cells by each cluster
            %% plot mean PSTHs of all clusters
            plot_clusters(app)
            app.clustering_type = 'GMM';
            
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            %update app.SharedCovariance accordigng to users choice
            selectedButton = app.ButtonGroup.SelectedObject;
            if strcmp(selectedButton, 'Shered')
                app.SharedCovariance = 1;
            else
                app.SharedCovariance = 2;
            end
        end

        % Selection changed function: ButtonGroup_2
        function ButtonGroup_2SelectionChanged(app, event)
            %update app.covariance accordigng to users choice
            selectedButton = app.ButtonGroup_2.SelectedObject;
            if strcmp(selectedButton, 'Diagonal')
                app.covariance = 1;
            else
                app.covariance = 2;
            end
        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            %save all data to app.clusters
            app.clusters.coeff = app.coeff; %save for adding and clustering more clusters
            app.clusters.response_bins = app.response_bins;
            app.clusters.normalize2bound = app.normalizeCheckBox.Value;
            app.clusters.pc_matrix = app.pc_matrix;
            app.clusters.clustering_binsize = app.ClusteringbinsizesecEditField.Value;
            if app.UsebestNDCheckBox.Value
                app.clusters.intensity_used = 'Best';
            else
                app.clusters.intensity_used = app.NDforclusteringEditField.Value;
            end
            app.clusters.figure = app.clusters_figure
            
            
            %save parameters difrent for GMM and DBSCAN
            if strcmp(app.clustering_type, 'GMM')
                app.clusters.gm = app.gmBest; %save the clusters models
            elseif strcmp(app.clustering_type, 'DBSCAN')
                app.clusters.epsilon = app.epsilonEditField.Value;
                app.clusters.minpts = app.minptsEditField.Value;
            end
            %prealocate for a metrix of psth's for all cells
            app.clusters.matrix = cell(length(app.idx), 3);
            app.clusters.matrix = [app.clusters.matrix num2cell(app.idx) num2cell(app.psth_tabel)];
            row = 0;
            %prealocate for clusters.cluster_cell wich is a matrix of all
            %cells in each cluster
            for i = 1:app.cluster_num
               cluster_name = ['cluster_' num2str(i)];
               app.clusters.cluster_cells.(cluster_name) = cell(1, 1); 
            end
            %run over all the cells analized. add each cell the cluster
            %number and add the psth of the cell to clusters.matrix and all
            %the cell data to clusters.cluster_cells of the relevant
            %cluster number
            for w = 1:length(app.all_files)
                file_name = app.file{1, w}(1:(end-4)); %save the name of the recording and take of the '.mat' apendix.
                for a = 1:length(app.all_files{w}.all_data)
                    cell_names = fieldnames(app.all_files{w}.all_data);
                    for i = 1:length(cell_names)
                        row = row + 1;
                        if app.idx(row) > 0 %check if not a noise cluster from DBSCAN (then idx(row) = -1)
                            %add the cell data to each cluster in the
                            %cluster.cluster_cells struct
                            cluster_name = ['cluster_' num2str(app.idx(row))]; 
                            app.clusters.cluster_cells.(cluster_name){end} = app.all_files{w}.all_data.(cell_names{i}); %insert the all cell data to the cluster id
                            app.clusters.cluster_cells.(cluster_name){end}.cell_name = {cell_names(i)}; %add the cell name
                            app.clusters.cluster_cells.(cluster_name){end}.exp_name = [file_name '_' num2str(a)]; % add the cell experiment (_1 is befor and _2 is afer injeciton)
                            app.clusters.cluster_cells.(cluster_name){end + 1} = []; % add a new empty cell to the end of the clusters for the next loop step 
                        end
                        %add the id's to clusters.matrix
                        app.clusters.matrix{row, 1} = file_name; %add file name
                        app.clusters.matrix{row, 2} = a; %add part of recording
                        app.clusters.matrix{row, 3} = cell_names{i}; %add cell name
                        app.all_files{w}.all_data.(cell_names{i}).cluster = app.idx(row); %add cluster name
                        
                    end
                    
                end
            end
            %% save app.clusters
            [file,path] = uiputfile('clusters.mat');
            filename = fullfile(path, file);
            clusters = app.clusters;
            save(filename, 'clusters', '-mat')
            
            
            %% save data files after inserting clusters
            for i = 1:length(app.all_files)
                all_data = app.all_files{i}.all_data;
                save(app.datafile{i}, 'all_data', '-mat')
            end 
        end

        % Button pushed function: PlotkdistanceButton_3
        function PlotkdistanceButtonValueChanged(app, event)
             %plot k - distance graph to estimate epsilon (from the knee of
            %the plot)
            %set parameters
            minpts = app.minptsEditField.Value;
            X = app.pc_matrix;
            kD = pdist2(X,X,'euc','Smallest',minpts); % The minpts smallest pairwise distances
            
            %plot
            plot(app.UIAxes2, sort(kD(end,:)));
            xlabel(app.UIAxes2, ['Points sorted with ' num2str(minpts) 'th nearest distances'])
            ylabel(app.UIAxes2,  [num2str(minpts) 'th nearest distances'])
            
        end

        % Button pushed function: ClusterButton_3
        function ClusterButton_2ValueChanged(app, event)
            %cluster DBSCAN
            app.clustering_type = 'DBSCAN';
            minpts = app.minptsEditField.Value;
            epsilon = app.epsilonEditField.Value;
            app.idx = dbscan(app.pc_matrix,epsilon,minpts); %clustar and create ids for the cluster identity of each cell 
            app.cluster_num = length(unique(abs(app.idx))); %update the cluster num for ploting the clusters. do not plot the noise cells who there idx = -1(that is way abs is used).
            plot_clusters(app)
        end

        % Button pushed function: celldisplayappButton
        function celldisplayappButtonPushed(app, event)
            cell_display_1
        end

        % Button pushed function: ManualButton
        function ManualButtonPushed(app, event)
             winopen('USER_MANUAL.pdf')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoaddatafilesButton = uibutton(app.UIFigure, 'push');
            app.LoaddatafilesButton.ButtonPushedFcn = createCallbackFcn(app, @LoaddatafilesButtonPushed, true);
            app.LoaddatafilesButton.Icon = 'load-icon-3.jpg';
            app.LoaddatafilesButton.Text = 'Load data files';
            app.normalizeCheckBox = uicheckbox(app.UIFigure);
            app.normalizeCheckBox.Text = 'normalize';
            app.normalizeCheckBox.Value = true;
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GMMTab = uitab(app.TabGroup);
            app.GMMTab.Title = 'GMM';
            app.maxclustersEditFieldLabel = uilabel(app.GMMTab);
            app.maxclustersEditFieldLabel.Text = 'max clusters';
            app.maxclustersEditField = uieditfield(app.GMMTab, 'numeric');
            app.maxclustersEditField.Value = 8;
            app.ButtonGroup = uibuttongroup(app.GMMTab);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.SheredButton = uiradiobutton(app.ButtonGroup);
            app.SheredButton.Text = 'Shered';
            app.SheredButton.Value = true;
            app.UnsheredButton = uiradiobutton(app.ButtonGroup);
            app.UnsheredButton.Text = 'Unshered';
            app.PreclusterButton = uibutton(app.GMMTab, 'push');
            app.PreclusterButton.ButtonPushedFcn = createCallbackFcn(app, @PreclusterButtonPushed, true);
            app.PreclusterButton.Icon = '69-691530_analysis-flat-icon-png-transparent-png.png';
            app.PreclusterButton.Text = 'Pre cluster';
            app.numberofclustersEditFieldLabel = uilabel(app.GMMTab);
            app.numberofclustersEditFieldLabel.Text = 'number of clusters';
            app.numberofclustersEditField = uieditfield(app.GMMTab, 'numeric');
            app.numberofclustersEditField.Value = 6;
            app.ClusterButton = uibutton(app.GMMTab, 'push');
            app.ClusterButton.ButtonPushedFcn = createCallbackFcn(app, @ClusterButtonPushed, true);
            app.ClusterButton.Icon = 'clusters.png';
            app.ClusterButton.Text = 'Cluster';
            app.ButtonGroup_2 = uibuttongroup(app.GMMTab);
            app.ButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_2SelectionChanged, true);
            app.DiagonalButton = uiradiobutton(app.ButtonGroup_2);
            app.DiagonalButton.Text = 'Diagonal';
            app.DiagonalButton.Value = true;
            app.FullButton = uiradiobutton(app.ButtonGroup_2);
            app.FullButton.Text = 'Full';
            app.UIAxes = uiaxes(app.GMMTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes_2 = uiaxes(app.GMMTab);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes_3 = uiaxes(app.GMMTab);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_3.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.DBSCANTab = uitab(app.TabGroup);
            app.DBSCANTab.Title = 'DBSCAN';
            app.minptsLabel = uilabel(app.DBSCANTab);
            app.minptsLabel.Text = 'minpts:';
            app.minptsEditField = uieditfield(app.DBSCANTab, 'numeric');
            app.minptsEditField.Value = 2;
            app.epsilonEditFieldLabel = uilabel(app.DBSCANTab);
            app.epsilonEditFieldLabel.Text = 'epsilon:';
            app.epsilonEditField = uieditfield(app.DBSCANTab, 'numeric');
            app.epsilonEditField.Value = 2;
            app.NumberofPCsLabel = uilabel(app.DBSCANTab);
            app.NumberofPCsLabel.Text = 'Number of PCs:';
            app.NumberofPCsEditField = uieditfield(app.DBSCANTab, 'numeric');
            app.PlotkdistanceButton_3 = uibutton(app.DBSCANTab, 'push');
            app.PlotkdistanceButton_3.ButtonPushedFcn = createCallbackFcn(app, @PlotkdistanceButtonValueChanged, true);
            app.PlotkdistanceButton_3.Icon = 'plot.jpeg';
            app.PlotkdistanceButton_3.Text = 'Plot k distance';
            app.ClusterButton_3 = uibutton(app.DBSCANTab, 'push');
            app.ClusterButton_3.ButtonPushedFcn = createCallbackFcn(app, @ClusterButton_2ValueChanged, true);
            app.ClusterButton_3.Icon = 'clusters.png';
            app.ClusterButton_3.Text = 'Cluster';
            app.UIAxes2 = uiaxes(app.DBSCANTab);
            title(app.UIAxes2, 'k-distance')
            xlabel(app.UIAxes2, 'Points sorted with 2th nearest distances')
            ylabel(app.UIAxes2, '2th nearest distances')
            zlabel(app.UIAxes2, 'Z')
            app.saveButton = uibutton(app.UIFigure, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Icon = 'save.png';
            app.saveButton.Text = 'save';
            app.celldisplayappButton = uibutton(app.UIFigure, 'push');
            app.celldisplayappButton.ButtonPushedFcn = createCallbackFcn(app, @celldisplayappButtonPushed, true);
            app.celldisplayappButton.Icon = 'plot.jpeg';
            app.celldisplayappButton.Text = 'cell display app';
            app.ManualButton = uibutton(app.UIFigure, 'push');
            app.ManualButton.ButtonPushedFcn = createCallbackFcn(app, @ManualButtonPushed, true);
            app.ManualButton.Icon = 'manual.png';
            app.ManualButton.Text = 'Manual';
            app.SmoothdataCheckBox = uicheckbox(app.UIFigure);
            app.SmoothdataCheckBox.Text = 'Smoothdata';
            app.ClusteringbinsizesecEditFieldLabel = uilabel(app.UIFigure);
            app.ClusteringbinsizesecEditFieldLabel.Text = 'Clustering binsize (sec):';
            app.ClusteringbinsizesecEditField = uieditfield(app.UIFigure, 'numeric');
            app.ClusteringbinsizesecEditField.Value = 0.5;
            app.NDforclusteringEditFieldLabel = uilabel(app.UIFigure);
            app.NDforclusteringEditFieldLabel.Text = 'ND for clustering';
            app.NDforclusteringEditField = uieditfield(app.UIFigure, 'numeric');
            app.NDforclusteringEditField.Value = 2;
            app.UsebestNDCheckBox = uicheckbox(app.UIFigure);
            app.UsebestNDCheckBox.Text = 'Use best ND';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = clustring2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end