classdef clustring3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RegularizationValueEditField    matlab.ui.control.NumericEditField
        RegularizationValueEditFieldLabel  matlab.ui.control.Label
        multiplybySTDscoreCheckBox      matlab.ui.control.CheckBox
        intensitiestocheckEditField     matlab.ui.control.NumericEditField
        intensitiestocheckEditFieldLabel  matlab.ui.control.Label
        usefirstsugnificantNDCheckBox   matlab.ui.control.CheckBox
        sugnificantcellsEditField       matlab.ui.control.NumericEditField
        sugnificantcellsEditFieldLabel  matlab.ui.control.Label
        numberofcellsEditField          matlab.ui.control.NumericEditField
        numberofcellsEditFieldLabel     matlab.ui.control.Label
        maxpvalueEditField              matlab.ui.control.NumericEditField
        maxpvalueEditFieldLabel         matlab.ui.control.Label
        UsebestNDCheckBox_2             matlab.ui.control.CheckBox
        VarienceexplanidEditField       matlab.ui.control.NumericEditField
        VarienceexplanidEditFieldLabel  matlab.ui.control.Label
        PCAButton                       matlab.ui.control.Button
        PlotclusteringdataCheckBox      matlab.ui.control.CheckBox
        clsuteronlysugnificantCheckBox  matlab.ui.control.CheckBox
        NDforclusteringEditField        matlab.ui.control.NumericEditField
        NDforclusteringEditFieldLabel   matlab.ui.control.Label
        ClusteringbinsizesecEditField   matlab.ui.control.NumericEditField
        ClusteringbinsizesecEditFieldLabel  matlab.ui.control.Label
        SmoothdataCheckBox              matlab.ui.control.CheckBox
        ManualButton                    matlab.ui.control.Button
        celldisplayappButton            matlab.ui.control.Button
        saveButton                      matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        GMMTab                          matlab.ui.container.Tab
        ButtonGroup_2                   matlab.ui.container.ButtonGroup
        FullButton                      matlab.ui.control.RadioButton
        DiagonalButton                  matlab.ui.control.RadioButton
        ClusterButton                   matlab.ui.control.Button
        numberofclustersEditField       matlab.ui.control.NumericEditField
        numberofclustersEditFieldLabel  matlab.ui.control.Label
        PreclusterButton                matlab.ui.control.Button
        ButtonGroup                     matlab.ui.container.ButtonGroup
        UnsheredButton                  matlab.ui.control.RadioButton
        SheredButton                    matlab.ui.control.RadioButton
        maxclustersEditField            matlab.ui.control.NumericEditField
        maxclustersEditFieldLabel       matlab.ui.control.Label
        UIAxes_4                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        DBSCANTab                       matlab.ui.container.Tab
        ClusterButton_3                 matlab.ui.control.Button
        PlotkdistanceButton_3           matlab.ui.control.Button
        NumberofPCsEditField            matlab.ui.control.NumericEditField
        NumberofPCsLabel                matlab.ui.control.Label
        epsilonEditField                matlab.ui.control.NumericEditField
        epsilonEditFieldLabel           matlab.ui.control.Label
        minptsEditField                 matlab.ui.control.NumericEditField
        minptsLabel                     matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        normalizeCheckBox               matlab.ui.control.CheckBox
        LoaddatafilesButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        %%
        %changeable parameters befor for loading the file:
        response_bins = 3:16 % bins of the time of response taken for the clustering
        on = 3; %on response in sec
        bin_size = 0.1; %in sec
        off = 12.8; %off response in sec
        min_variance = 95; % The minimal varience (of the PSTH data) expressed by the PC taken for the clustering
        
        
        %%
        %defalt variabels changeable in the app (if you change the defalt
        %change the defalt value in the disign view):
        covariance = 1 % parameter for GMm clustering. 1 is diagonal (defalt) 2 is full
        SharedCovariance = 1 % parameter for GMm clustering. 1 is shered (defalt) 2 is unshered


        %%
        % variabels used by the app (do not change):
        all_files % a stucture contaning all files loaded to the app
        psth_tabel %  matrix whith PSTHs of all the cells In the data files (befor and after injection are treated as difrent cells).
        psth_tabel_norm %psth tabel after the normalization to bound step. will contain the PSTH matrix also if the normilize option is not used.
        coeff %principal component coefficients created by the PCA anlisis and saved in order to add more cells to the GMM in the cell display app
        % Matrix of PSTHs of all cells (only the relevant response time - the bins in response_bins)
        % expressed by the PCs accounted for 95% of the varience:
        pc_matrix
        cluster_num % number of clusters calculated
        gm % And arrey of all optional gmdistribution model object from the tuning of the GMm
        gmBest % gm of the best parameters chosen by the user
        clusters % variable holding all the clusters data
        idx % index of the cluster of each cell
        file % the name of the files loaded
        datafile % name of datafiles loaded
        clustering_type % type of clustreing (GMM/DBSCAN)
        
        binned_psth % the psth for pca after bining to 1 bin for a sec
        clusters_figure % figure saving all clusters
        clusters_figure_smooth % Description
        bic_gradient_figure % Description
        aic_figure % Description
        aic_gradient % Description
        
        relevant_intensity % relevant intensity for the clustering
        intensity_used % vector of intensities used for clustering in each cell
        aic_score % Description
    end
    
    
    methods (Access = private)
        
        function Create_pc_matrix(app) %run pca on the data
            new_response_bins = app.response_bins(1)/app.ClusteringbinsizesecEditField.Value:app.response_bins(end)/app.ClusteringbinsizesecEditField.Value;
            [app.coeff,score,~,~,explained] = pca(app.psth_tabel_norm(:,new_response_bins));
            % Find the number of components required to explain at least 95% variability.
            figure
            sum_explained = cumsum(explained);
            plot(sum_explained)
            idx1 = find(sum_explained>app.VarienceexplanidEditField.Value ,1);
            if app.multiplybySTDscoreCheckBox.Value %multiplay the coeff by the STD of the score (worked for shai)
               stdscore=std(score,[],1);
               app.pc_matrix = [];
               for i = 1: idx1
                   app.pc_matrix = [app.pc_matrix, stdscore(i).*app.coeff(:,i)];
               end

            else
                app.pc_matrix = score(:, 1:idx1); %relevant component matrix for clustring
            end
            app.NumberofPCsEditField.Value = idx1; %display the PC number for estemating minpts for DBSCAN
            app.minptsEditField.Value = idx1 + 1; %change the minpts difalt value to one plus the number of dimensions of the input data
        end
        
        function plot_clusters(app)
            %% plot the mean+STD PSTH off all the clusters (in a new figure besauce the amount of clusters might change - and to use shadedErrorBar that is not sutibael fot UIAXES)
            
            app.clusters_figure_smooth = figure;
            app.clusters_figure = figure;
            
            %prealocate for the PSTH parameters
            times = size(app.psth_tabel ,2); %size of PATH bins
            app.clusters.mean = zeros(app.cluster_num, times);
            app.clusters.std = app.clusters.mean;
            app.clusters.ste = app.clusters.std;
            app.clusters.num_of_cells = zeros(1, app.cluster_num); %number of cells in each cluster
            %create a variable for the time (x axes)
            x = ((((1:times)-1)*app.bin_size)-app.on);
            for i = 1:app.cluster_num
                %find all cells of each cluster
                indices = (app.idx == i);
                %add the values to the app.cluster variable
                app.clusters.num_of_cells(i) = sum(indices);
                app.clusters.mean(i, :) = mean(app.psth_tabel(indices, :), 1);
                app.clusters.std(i, :) = std(app.psth_tabel(indices, :), 0, 1);
                app.clusters.ste(i, :) = app.clusters.std(i, :)/sqrt(length(indices));
                %plot each cluster in a new subplot
                set(0,'CurrentFigure' , app.clusters_figure)
                subplot(2, ceil(app.cluster_num/2),i)
                hold on
                shadedErrorBar(x, app.clusters.mean(i ,:), app.clusters.ste(i, :), 'lineprops', '-r')
                %plot the data taken for clustering
                if app.PlotclusteringdataCheckBox.Value
                    binns_used = app.response_bins(1)/app.bin_size:app.response_bins(end)/app.bin_size;
                    clustering_psth = app.clusters.mean(i ,binns_used);
                    %bin psth
                    binsize = app.bin_size/app.ClusteringbinsizesecEditField.Value; %bin size in sec
                    cut_end = floor(length(clustering_psth)*binsize)/binsize;
                    bin_psth = mean(reshape(clustering_psth(1:cut_end), 1/binsize, []));
                    new_x = x(binns_used(1)):app.ClusteringbinsizesecEditField.Value:x(binns_used(end));
                    plot(new_x(1:length(bin_psth)), bin_psth,   'LineWidth', 1.5)
                end
                
                
                title (['cluster ' num2str(i)])
                subtitle(['number of cells = '  num2str(app.clusters.num_of_cells(i))])
                xlabel('Time (sec)')
                ylabel('Firing rate (Hz)')
                xlim([x(1) x(end)])
                xline(0 , '-' , 'on')
                xline(app.off-app.on , '-' , 'off');
                %plot mean +- std ass shaded error bar
                hold off
                
                if app.SmoothdataCheckBox.Value
                    set(0,'CurrentFigure' , app.clusters_figure_smooth)
                    subplot(2, ceil(app.cluster_num/2),i)
                    %bin psth
                    
                    cut_end = floor(length(app.clusters.mean(i ,:))*app.bin_size)/app.bin_size;
                    bin_psth = mean(reshape(app.clusters.mean(i , 1:cut_end), 1/app.bin_size, []));
                    %interpulate PSTH
                    y = 1:length(bin_psth);
                    xq = 1:app.bin_size:length(bin_psth);
                    interpulated_psth = interpn(y, bin_psth, xq, 'pchip');
                    shadedErrorBar(x(1:length(interpulated_psth)), interpulated_psth, app.clusters.ste(i, 1:length(interpulated_psth)), 'lineprops', '-g')
                    title (['cluster ' num2str(i)])
                    subtitle(['number of cells = '  num2str(app.clusters.num_of_cells(i))])
                    xlabel('Time (sec)')
                    ylabel('Firing rate (Hz)')
                    xlim([x(1) x(end)])
                    xline(0 , '-' , 'on')
                    xline(app.off-app.on , '-' , 'off');
                    %plot mean +- std ass shaded error bar
                    hold off
                end
                
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PreclusterButton
        function PreclusterButtonPushed(app, event)
            %% gausian tuning - check the best parmeters for gausain model mixture clustring
            %set parameters:
            %Suppose k is the number of desired components or clusters, and Sigmais the covariance structure for all components.
            % Follow these steps to tune a GMM:
            %1. Choose a (k,Sigma) pair, and then fit a GMM using the chosen parameter specification and the entire data set.
            %2. Estimate the AIC and BIC.
            %3. Repeat steps 1 and 2 until you exhaust all (k, Sigma) pairs of interest.
            % Choose the fitted GMM that balances low AIC with simplicity.
            
            k = 1:app.maxclustersEditField.Value;
            nK = numel(k);
            Sigma = {'diagonal','full'};
            nSigma = numel(Sigma);
            SharedCovariance = {true,false};
            %SCtext = {'true','false'};
            nSC = numel(SharedCovariance);
            RegularizationValue = app.RegularizationValueEditField.Value;
            options = statset('MaxIter',100000);
            legends = {'Diagonal-shared','Full-shared','Diagonal-unshared',...
                'Full-unshared'};
            
            %Fit the GMMs using all parameter combination. Compute the AIC
            %and BIC for each fit.
            % Preallocation
            app.gm = cell(nK,nSigma,nSC);
            aic = zeros(nK,nSigma,nSC);
            bic = zeros(nK,nSigma,nSC);
            converged = false(nK,nSigma,nSC);
            app.aic_figure = figure;
%             app.bic_gradient_figure = figure;
%             app.aic_gradient = figure;
            app.aic_score = figure;
            
            
            % Fit all models
            bic_gradient = zeros(nSC*nSigma,nK);
            counts = 0;
            for m = 1:nSC
                for j = 1:nSigma
                    counts = counts + 1;
                    for i = 1:nK
                        app.gm{i,j,m} = fitgmdist(app.pc_matrix,k(i),...
                            'CovarianceType',Sigma{j},...
                            'SharedCovariance',SharedCovariance{m},...
                            'RegularizationValue',RegularizationValue,...
                            'Options',options);
                        aic(i,j,m) = app.gm{i,j,m}.AIC;
                        bic(i,j,m) = app.gm{i,j,m}.BIC;
                        converged(i,j,m) = app.gm{i,j,m}.Converged;
                    end
%                     set(0, 'CurrentFigure', app.bic_gradient_figure)
                      bic_comp(counts,:) = bic(:,j,m);
%                     bic_gradient(counts,:) = gradient(bic(:,j,m));
%                     subplot(nSC, nSigma, counts)
%                     plot(1:nK, bic_gradient(counts,:))
%                     xlabel('number of clusters');
%                     ylabel('BIC gradient');
%                     legend(legends(counts))
                    
%                     set(0, 'CurrentFigure', app.aic_figure)
                      aic_comp(counts,:) = aic(:,j,m);
%                     subplot(nSC, nSigma, counts)
%                     plot(1:nK, aic_comp(counts,:))
%                     xlabel('number of clusters');
%                     ylabel('AIC score');
%                     legend(legends(counts))
%                     
%                     set(0, 'CurrentFigure', app.aic_gradient)
%                     aic_gradient(counts,:) = gradient(aic(:,j,m));
%                     subplot(nSC, nSigma, counts)
%                     plot(1:nK, aic_gradient(counts,:))
%                     xlabel('number of clusters');
%                     ylabel('AIC gradient');
%                     legend(legends(counts))
                end
            end
            
%             E = evalclusters(app.pc_matrix,'gmdistribution','silhouette','klist',[1:10]);
%             figure
%             plot(E)
            
            
            %             %plot bic gradients
            %             plot(app.UIAxes_4, 1:nK, bic_comp)
            %             title(app.UIAxes_4, 'BIC','Interpreter','Latex');
            %             xlabel(app.UIAxes_4,'$k$','Interpreter','Latex');
            %             ylabel(app.UIAxes_4,'BIC');
            %             legend(app.UIAxes_4,{'Diagonal-shared','Full-shared','Diagonal-unshared',...
            %                 'Full-unshared'});
            
%             %plot bic gradients
%             plot(app.UIAxes_4, 1:nK, bic_comp)
%             title(app.UIAxes_4, 'BIC','Interpreter','Latex');
%             xlabel(app.UIAxes_4,'$k$','Interpreter','Latex');
%             ylabel(app.UIAxes_4,'BIC');
%             legend(app.UIAxes_4,{'Diagonal-shared','Full-shared','Diagonal-unshared',...
%                 'Full-unshared'});
%             
            
            %plot aic score
            set(0, 'CurrentFigure', app.aic_score)
            plot(1:nK, aic_comp)
            xlabel('number of clusters');
            ylabel('AIC score');
            legend(app.UIAxes_4,{'Diagonal-shared','Full-shared','Diagonal-unshared',...
                'Full-unshared'});
            %gm is a cell array containing all of the fitted gmdistribution model objects. All of the fitting instances converged.
            %Plot separate bar charts to compare the AIC and BIC among all fits. Group the bars by k.
            bar(app.UIAxes, reshape(aic,nK,nSigma*nSC));
            title(app.UIAxes, 'AIC For Various $k$ and $\Sigma$ Choices','Interpreter','latex');
            xlabel(app.UIAxes,'$k$','Interpreter','Latex');
            ylabel(app.UIAxes,'AIC');
            %ylim(app.UIAxes, [0 1.5*max(reshape(aic,nK,nSigma*nSC), [], "all")])
            legend(app.UIAxes,{'Diagonal-shared','Full-shared','Diagonal-unshared',...
                'Full-unshared'});
            
            
            bar(app.UIAxes_2,reshape(bic,nK,nSigma*nSC));
            title(app.UIAxes_2,'BIC For Various $k$ and $\Sigma$ Choices','Interpreter','latex');
            xlabel(app.UIAxes_2,'$k$','Interpreter','Latex');
            ylabel(app.UIAxes_2,'BIC');
            %ylim(app.UIAxes_2, [0 1.5*max(reshape(bic,nK,nSigma*nSC), [], "all")])
            legend(app.UIAxes_2,{'Diagonal-shared','Full-shared','Diagonal-unshared',...
                'Full-unshared'});
            
            %plot AIC + BIC
            bar(app.UIAxes_3, reshape((aic+bic),nK,nSigma*nSC));
            title(app.UIAxes_3, 'AIC + BIC For Various $k$ and $\Sigma$ Choices','Interpreter','latex');
            xlabel(app.UIAxes_3, '$k$','Interpreter','Latex');
            ylabel(app.UIAxes_3, 'AIC + BIC');
            %ylim(app.UIAxes_3, [0 1.5*max(reshape((aic+bic),nK,nSigma*nSC), [], "all")])
            legend(app.UIAxes_3, {'Diagonal-shared','Full-shared','Diagonal-unshared',...
                'Full-unshared'});
        end

        % Button pushed function: ClusterButton
        function ClusterButtonPushed(app, event)
            %% cluster using the best fitting model
            app.cluster_num = app.numberofclustersEditField.Value;
            app.gmBest = app.gm{app.cluster_num,app.covariance,app.SharedCovariance};%best parameters to cluster with
            app.idx = cluster(app.gmBest,app.pc_matrix); %index of all cells by each cluster
            %% plot mean PSTHs of all clusters
            plot_clusters(app)
            app.clustering_type = 'GMM';
            
        end

        % Button pushed function: LoaddatafilesButton
        function LoaddatafilesButtonPushed(app, event)
            %% load saved data
            [app.file, path] = uigetfile('*.mat','MultiSelect','on');%When the user clicks the load psetion button, a window should open to enable the user to select a file.
            cd(path)
            app.datafile = fullfile(path, app.file); %save path
            file_numes = length(app.datafile);
            app.all_files = cell(1, file_numes);
            for i = 1:file_numes
                app.all_files{1, i} = load(app.datafile{1,i});
                app.all_files{1, i} = app.all_files{1, i};
            end
            
            
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            %update app.SharedCovariance accordigng to users choice
            selectedButton = app.ButtonGroup.SelectedObject;
            if strcmp(selectedButton, 'Shered')
                app.SharedCovariance = 1;
            else
                app.SharedCovariance = 2;
            end
        end

        % Selection changed function: ButtonGroup_2
        function ButtonGroup_2SelectionChanged(app, event)
            %update app.covariance accordigng to users choice
            selectedButton = app.ButtonGroup_2.SelectedObject;
            if strcmp(selectedButton, 'Diagonal')
                app.covariance = 1;
            else
                app.covariance = 2;
            end
        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            %save all data to app.clusters
            app.clusters.coeff = app.coeff; %save for adding and clustering more clusters
            app.clusters.response_bins = app.response_bins;
            app.clusters.normalize2bound = app.normalizeCheckBox.Value;
            app.clusters.pc_matrix = app.pc_matrix;
            app.clusters.clustering_binsize = app.ClusteringbinsizesecEditField.Value;
            app.clusters.varience_explaind = app.VarienceexplanidEditField.Value;
            app.clusters.all_gm = app.gm;
            if app.clsuteronlysugnificantCheckBox.Value
                app.clusters.intensity_used = 'Best';
            else
                app.clusters.intensity_used = app.NDforclusteringEditField.Value;
            end
            app.clusters.figure = app.clusters_figure;
            app.clusters.figure_smooth = app.clusters_figure_smooth;
            app.clusters.aic_figure = app.aic_figure;
            app.clusters.bic_gradient_figure = app.bic_gradient_figure;
            
            
            %save parameters difrent for GMM and DBSCAN
            if strcmp(app.clustering_type, 'GMM')
                app.clusters.gm = app.gmBest; %save the clusters models
            elseif strcmp(app.clustering_type, 'DBSCAN')
                app.clusters.epsilon = app.epsilonEditField.Value;
                app.clusters.minpts = app.minptsEditField.Value;
            end
            %prealocate for a metrix of psth's for all cells
            app.clusters.matrix = cell(length(app.idx), 3);
            app.clusters.matrix = [app.clusters.matrix num2cell(app.idx) num2cell(app.psth_tabel)];
            row = 0;
            cell_number = 0;
            %prealocate for clusters.cluster_cell wich is a matrix of all
            %cells in each cluster
            for i = 1:app.cluster_num
                cluster_name = ['cluster_' num2str(i)];
                app.clusters.cluster_cells.(cluster_name) = cell(1, 1);
            end
            %run over all the cells analized. add each cell the cluster
            %number and add the psth of the cell to clusters.matrix and all
            %the cell data to clusters.cluster_cells of the relevant
            %cluster number
            for w = 1:length(app.all_files)
                file_name = app.file{1, w}(1:(end-4)); %save the name of the recording and take of the '.mat' apendix.
                for a = 1:length(app.all_files{w}.all_data)
                    cell_names = fieldnames(app.all_files{w}.all_data);
                    for i = 1:length(cell_names)
                        if app.clsuteronlysugnificantCheckBox.Value
                            cell_number = cell_number + 1;
                            if app.intensity_used(cell_number)
                                row = row + 1;
                                if app.idx(row) > 0 %check if not a noise cluster from DBSCAN (then idx(row) = -1)
                                    %add the cell data to each cluster in the
                                    %cluster.cluster_cells struct
                                    cluster_name = ['cluster_' num2str(app.idx(row))];
                                    app.clusters.cluster_cells.(cluster_name){end} = app.all_files{w}.all_data.(cell_names{i}); %insert the all cell data to the cluster id
                                    app.clusters.cluster_cells.(cluster_name){end}.cell_name = {cell_names(i)}; %add the cell name
                                    app.clusters.cluster_cells.(cluster_name){end}.exp_name = [file_name '_' num2str(a)]; % add the cell experiment (_1 is befor and _2 is afer injeciton)
                                    app.clusters.cluster_cells.(cluster_name){end + 1} = []; % add a new empty cell to the end of the clusters for the next loop step
                                end
                                %add the id's to clusters.matrix
                                app.clusters.matrix{row, 1} = file_name; %add file name
                                app.clusters.matrix{row, 2} = a; %add part of recording
                                app.clusters.matrix{row, 3} = cell_names{i}; %add cell name
                                app.all_files{w}.all_data.(cell_names{i}).cluster = app.idx(row); %add cluster name
                                %add location if mapping is included
                                if isfield(app.all_files{w}.all_data.(cell_names{i}), 'cordinates')
                                    position{row, 1} = app.all_files{w}.all_data.(cell_names{i}).position{1};
                                    x(row, 1) = app.all_files{w}.all_data.(cell_names{i}).cordinates.x{1};
                                    y(row, 1) = app.all_files{w}.all_data.(cell_names{i}).cordinates.y{1};
                                    z(row, 1) = app.all_files{w}.all_data.(cell_names{i}).cordinates.z{1};
                                end
                            end
                        else
                            row = row + 1;
                            
                            if app.idx(row) > 0 %check if not a noise cluster from DBSCAN (then idx(row) = -1)
                                %add the cell data to each cluster in the
                                %cluster.cluster_cells struct
                                cluster_name = ['cluster_' num2str(app.idx(row))];
                                app.clusters.cluster_cells.(cluster_name){end} = app.all_files{w}.all_data.(cell_names{i}); %insert the all cell data to the cluster id
                                app.clusters.cluster_cells.(cluster_name){end}.cell_name = {cell_names(i)}; %add the cell name
                                app.clusters.cluster_cells.(cluster_name){end}.exp_name = [file_name '_' num2str(a)]; % add the cell experiment (_1 is befor and _2 is afer injeciton)
                                app.clusters.cluster_cells.(cluster_name){end + 1} = []; % add a new empty cell to the end of the clusters for the next loop step
                            end
                            %add the id's to clusters.matrix
                            app.clusters.matrix{row, 1} = file_name; %add file name
                            app.clusters.matrix{row, 2} = a; %add part of recording
                            app.clusters.matrix{row, 3} = cell_names{i}; %add cell name
                            app.all_files{w}.all_data.(cell_names{i}).cluster = app.idx(row); %add cluster name
                            %add location if mapping is included
                            if isfield(app.all_files{w}.all_data.(cell_names{i}), 'cordinates')
                                position{row, 1} = app.all_files{w}.all_data.(cell_names{i}).position{1};
                                x(row, 1) = app.all_files{w}.all_data.(cell_names{i}).cordinates.x{1};
                                y(row, 1) = app.all_files{w}.all_data.(cell_names{i}).cordinates.y{1};
                                z(row, 1) = app.all_files{w}.all_data.(cell_names{i}).cordinates.z{1};
                            else
                                position{row, 1} = nan;
                                x(row, 1) = nan;
                                y(row, 1) = nan;
                                z(row, 1) = nan;
                            end
                        end
                        
                    end
                    
                end
            end
            id = str2double(cellfun(@(x) x(6:end) ,app.clusters.matrix(:, 3)', 'UniformOutput', false))';
            experiment_name =  app.clusters.matrix(:, 1);
            before_after_injection = app.clusters.matrix(:, 2);
            cluster = cell2mat(app.clusters.matrix(:, 4));
            brain_render_table = table(id, experiment_name, before_after_injection, cluster, position, x, y, z);
            psth = array2table(app.psth_tabel);
            
            brain_render_table = [brain_render_table psth];
            app.clusters.brain_render_table = brain_render_table;
            
            
            %% save app.clusters
            [file,path] = uiputfile('clusters.mat');
            filename = fullfile(path, file);
            clusters = app.clusters;
            save(filename, 'clusters', '-mat')
            
            writetable(brain_render_table, fullfile(path, 'clustering_data.csv'), 'Delimiter', ',')
            
            %% save data files after inserting clusters
            for i = 1:length(app.all_files)
                all_data = app.all_files{i}.all_data;
                save(app.datafile{i}, 'all_data', '-mat')
            end
        end

        % Button pushed function: PlotkdistanceButton_3
        function PlotkdistanceButtonValueChanged(app, event)
            %plot k - distance graph to estimate epsilon (from the knee of
            %the plot)
            %set parameters
            minpts = app.minptsEditField.Value;
            X = app.pc_matrix;
            kD = pdist2(X,X,'euc','Smallest',minpts); % The minpts smallest pairwise distances
            
            %plot
            plot(app.UIAxes2, sort(kD(end,:)));
            xlabel(app.UIAxes2, ['Points sorted with ' num2str(minpts) 'th nearest distances'])
            ylabel(app.UIAxes2,  [num2str(minpts) 'th nearest distances'])
            
        end

        % Button pushed function: ClusterButton_3
        function ClusterButton_2ValueChanged(app, event)
            %cluster DBSCAN
            app.clustering_type = 'DBSCAN';
            minpts = app.minptsEditField.Value;
            epsilon = app.epsilonEditField.Value;
            app.idx = dbscan(app.pc_matrix,epsilon,minpts); %clustar and create ids for the cluster identity of each cell
            app.cluster_num = length(unique(abs(app.idx))); %update the cluster num for ploting the clusters. do not plot the noise cells who there idx = -1(that is way abs is used).
            plot_clusters(app)
        end

        % Button pushed function: celldisplayappButton
        function celldisplayappButtonPushed(app, event)
            cell_display_1
        end

        % Button pushed function: ManualButton
        function ManualButtonPushed(app, event)
            winopen('USER_MANUAL.pdf')
        end

        % Button pushed function: PCAButton
        function PCAButtonPushed(app, event)
            %prealocate and axtract a psth tabel from all files
            row = 0;
            number_of_cells = 0;
            for w = 1:length(app.all_files)
                for a = 1:numel(app.all_files{1, w}.all_data)
                    cell_names = fieldnames(app.all_files{1, w}.all_data);
                    %extract the PSTH for each cell from the best
                    %responding intensty as determend in the cell display
                    %app (by the lowest pvalue).
                    for i = 1:numel(cell_names)
                        data = app.all_files{1, w}.all_data.(cell_names{i});
                        number_of_cells = number_of_cells + 1;
                        row = row + 1;
                        %insert the psth of the best/chosen intensity
                        if app.UsebestNDCheckBox_2.Value
                            app.psth_tabel(row, :) = (data.intensities(data.max.intensity).psth.mean)'; %complicated to get size out of loop so i did it with no prealocating
                        else
                            app.psth_tabel(row, :) = (data.intensities(app.NDforclusteringEditField.Value).psth.mean)'; %complicated to get size out of loop so i did it with no prealocating
                        end
                        %normalize to bounds (if normelized check box is
                        %checked. normalizing to bounds is devideing all the PSTH
                        % by the max abs(value) which makes the bounds of the PSTH between 1 and -1
                        %It gives more weight to the PSTH waveform and less
                        %less weight to the firing rate change magnitude.


                        binsize = app.bin_size/app.ClusteringbinsizesecEditField.Value; %bin size in sec
                        cut_end = floor(length(app.psth_tabel(row, :))*binsize)/binsize;
                        app.binned_psth(row, :) = mean(reshape(app.psth_tabel(row, 1:cut_end), 1/binsize, []));


                        if app.normalizeCheckBox.Value
                            app.psth_tabel_norm(row, :) = app.binned_psth(row, :)/max(abs(app.binned_psth(row, :)));
                        else
                            app.psth_tabel_norm(row, :) =  app.binned_psth(row, :);
                        end
                        
                        
                        %cluster only sugnificant
                        if app.clsuteronlysugnificantCheckBox.Value
                            if app.UsebestNDCheckBox_2.Value
                                app.relevant_intensity = data.max.intensity;
                            else
                                app.relevant_intensity = app.NDforclusteringEditField.Value;
                            end
                            if app.usefirstsugnificantNDCheckBox.Value
                                j = 1;
                                while j < app.intensitiestocheckEditField.Value %chack the intensties from ND=1 to ND = intensities to check input field
                                    if data.intensities(j).on.value > app.maxpvalueEditField.Value && data.intensities(j).off.value >  app.maxpvalueEditField.Value && data.intensities(j).sustained.value >  app.maxpvalueEditField.Value  %no sugnificant response
                                        j = j + 1;
                                        if j == app.intensitiestocheckEditField.Value
                                            app.intensity_used(number_of_cells) = 0;
                                            app.psth_tabel(row, :) = [];
                                            app.psth_tabel_norm(row, :) = [];
                                            row = row-1;
                                        end
                                    else
                                        app.all_files{1, w}.all_data.(cell_names{i}).max.intensity = j;
                                        app.psth_tabel(row, :) = (data.intensities(j).psth.mean)'; % add the new relevant intensity
                                        app.intensity_used(number_of_cells) = j;
                                        j = app.intensitiestocheckEditField.Value;
                                    end
                                end
                            else
                                if data.intensities(app.relevant_intensity).on.value > app.maxpvalueEditField.Value && data.intensities(app.relevant_intensity).off.value >  app.maxpvalueEditField.Value  %no sugnificant response
                                    app.psth_tabel(row, :) = [];
                                    app.psth_tabel_norm(row, :) = [];
                                    row = row-1;
                                end
                            end
                        end
                    end
                    
                end
            end
            app.sugnificantcellsEditField.Value = row;
            app.numberofcellsEditField.Value = number_of_cells;
            %app.psth_tabel = app.binned_psth;
            Create_pc_matrix(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoaddatafilesButton = uibutton(app.UIFigure, 'push');
            app.LoaddatafilesButton.ButtonPushedFcn = createCallbackFcn(app, @LoaddatafilesButtonPushed, true);
            app.LoaddatafilesButton.Icon = 'load-icon-3.jpg';
            app.LoaddatafilesButton.Text = 'Load data files';
            app.normalizeCheckBox = uicheckbox(app.UIFigure);
            app.normalizeCheckBox.Text = 'normalize';
            app.normalizeCheckBox.Value = true;
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GMMTab = uitab(app.TabGroup);
            app.GMMTab.Title = 'GMM';
            app.UIAxes = uiaxes(app.GMMTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes_2 = uiaxes(app.GMMTab);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes_3 = uiaxes(app.GMMTab);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_3.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes_4 = uiaxes(app.GMMTab);
            title(app.UIAxes_4, 'Title')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_4.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.maxclustersEditFieldLabel = uilabel(app.GMMTab);
            app.maxclustersEditFieldLabel.Text = 'max clusters';
            app.maxclustersEditField = uieditfield(app.GMMTab, 'numeric');
            app.maxclustersEditField.Value = 8;
            app.ButtonGroup = uibuttongroup(app.GMMTab);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.SheredButton = uiradiobutton(app.ButtonGroup);
            app.SheredButton.Text = 'Shered';
            app.SheredButton.Value = true;
            app.UnsheredButton = uiradiobutton(app.ButtonGroup);
            app.UnsheredButton.Text = 'Unshered';
            app.PreclusterButton = uibutton(app.GMMTab, 'push');
            app.PreclusterButton.ButtonPushedFcn = createCallbackFcn(app, @PreclusterButtonPushed, true);
            app.PreclusterButton.Icon = '69-691530_analysis-flat-icon-png-transparent-png.png';
            app.PreclusterButton.Text = 'Pre cluster';
            app.numberofclustersEditFieldLabel = uilabel(app.GMMTab);
            app.numberofclustersEditFieldLabel.Text = 'number of clusters';
            app.numberofclustersEditField = uieditfield(app.GMMTab, 'numeric');
            app.numberofclustersEditField.Value = 6;
            app.ClusterButton = uibutton(app.GMMTab, 'push');
            app.ClusterButton.ButtonPushedFcn = createCallbackFcn(app, @ClusterButtonPushed, true);
            app.ClusterButton.Icon = 'clusters.png';
            app.ClusterButton.Text = 'Cluster';
            app.ButtonGroup_2 = uibuttongroup(app.GMMTab);
            app.ButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_2SelectionChanged, true);
            app.DiagonalButton = uiradiobutton(app.ButtonGroup_2);
            app.DiagonalButton.Text = 'Diagonal';
            app.DiagonalButton.Value = true;
            app.FullButton = uiradiobutton(app.ButtonGroup_2);
            app.FullButton.Text = 'Full';
            app.DBSCANTab = uitab(app.TabGroup);
            app.DBSCANTab.Title = 'DBSCAN';
            app.UIAxes2 = uiaxes(app.DBSCANTab);
            title(app.UIAxes2, 'k-distance')
            xlabel(app.UIAxes2, 'Points sorted with 2th nearest distances')
            ylabel(app.UIAxes2, '2th nearest distances')
            zlabel(app.UIAxes2, 'Z')
            app.minptsLabel = uilabel(app.DBSCANTab);
            app.minptsLabel.Text = 'minpts:';
            app.minptsEditField = uieditfield(app.DBSCANTab, 'numeric');
            app.minptsEditField.Value = 2;
            app.epsilonEditFieldLabel = uilabel(app.DBSCANTab);
            app.epsilonEditFieldLabel.Text = 'epsilon:';
            app.epsilonEditField = uieditfield(app.DBSCANTab, 'numeric');
            app.epsilonEditField.Value = 2;
            app.NumberofPCsLabel = uilabel(app.DBSCANTab);
            app.NumberofPCsLabel.Text = 'Number of PCs:';
            app.NumberofPCsEditField = uieditfield(app.DBSCANTab, 'numeric');
            app.PlotkdistanceButton_3 = uibutton(app.DBSCANTab, 'push');
            app.PlotkdistanceButton_3.ButtonPushedFcn = createCallbackFcn(app, @PlotkdistanceButtonValueChanged, true);
            app.PlotkdistanceButton_3.Icon = 'plot.jpeg';
            app.PlotkdistanceButton_3.Text = 'Plot k distance';
            app.ClusterButton_3 = uibutton(app.DBSCANTab, 'push');
            app.ClusterButton_3.ButtonPushedFcn = createCallbackFcn(app, @ClusterButton_2ValueChanged, true);
            app.ClusterButton_3.Icon = 'clusters.png';
            app.ClusterButton_3.Text = 'Cluster';
            app.saveButton = uibutton(app.UIFigure, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Icon = 'save.png';
            app.saveButton.Text = 'save';
            app.celldisplayappButton = uibutton(app.UIFigure, 'push');
            app.celldisplayappButton.ButtonPushedFcn = createCallbackFcn(app, @celldisplayappButtonPushed, true);
            app.celldisplayappButton.Icon = 'plot.jpeg';
            app.celldisplayappButton.Text = 'cell display app';
            app.ManualButton = uibutton(app.UIFigure, 'push');
            app.ManualButton.ButtonPushedFcn = createCallbackFcn(app, @ManualButtonPushed, true);
            app.ManualButton.Icon = 'manual.png';
            app.ManualButton.Text = 'Manual';
            app.SmoothdataCheckBox = uicheckbox(app.UIFigure);
            app.SmoothdataCheckBox.Text = 'Smoothdata';
            app.SmoothdataCheckBox.Value = true;
            app.ClusteringbinsizesecEditFieldLabel = uilabel(app.UIFigure);
            app.ClusteringbinsizesecEditFieldLabel.Text = 'Clustering binsize (sec):';
            app.ClusteringbinsizesecEditField = uieditfield(app.UIFigure, 'numeric');
            app.ClusteringbinsizesecEditField.Value = 0.5;
            app.NDforclusteringEditFieldLabel = uilabel(app.UIFigure);
            app.NDforclusteringEditFieldLabel.Text = 'ND for clustering';
            app.NDforclusteringEditField = uieditfield(app.UIFigure, 'numeric');
            app.NDforclusteringEditField.Value = 1;
            app.clsuteronlysugnificantCheckBox = uicheckbox(app.UIFigure);
            app.clsuteronlysugnificantCheckBox.Text = 'clsuter only sugnificant';
            app.PlotclusteringdataCheckBox = uicheckbox(app.UIFigure);
            app.PlotclusteringdataCheckBox.Text = 'Plot clustering data';
            app.PlotclusteringdataCheckBox.Value = true;
            app.PCAButton = uibutton(app.UIFigure, 'push');
            app.PCAButton.ButtonPushedFcn = createCallbackFcn(app, @PCAButtonPushed, true);
            app.PCAButton.Text = 'PCA';
            app.VarienceexplanidEditFieldLabel = uilabel(app.UIFigure);
            app.VarienceexplanidEditFieldLabel.Text = 'Varience explanid';
            app.VarienceexplanidEditField = uieditfield(app.UIFigure, 'numeric');
            app.VarienceexplanidEditField.Value = 90;
            app.UsebestNDCheckBox_2 = uicheckbox(app.UIFigure);
            app.UsebestNDCheckBox_2.Text = 'Use best ND';
            app.maxpvalueEditFieldLabel = uilabel(app.UIFigure);
            app.maxpvalueEditFieldLabel.Text = 'max p value';
            app.maxpvalueEditField = uieditfield(app.UIFigure, 'numeric');
            app.maxpvalueEditField.Value = 0.05;
            app.numberofcellsEditFieldLabel = uilabel(app.UIFigure);
            app.numberofcellsEditFieldLabel.Text = 'number of cells';
            app.numberofcellsEditField = uieditfield(app.UIFigure, 'numeric');
            app.sugnificantcellsEditFieldLabel = uilabel(app.UIFigure);
            app.sugnificantcellsEditFieldLabel.Text = 'sugnificant cells';
            app.sugnificantcellsEditField = uieditfield(app.UIFigure, 'numeric');
            app.usefirstsugnificantNDCheckBox = uicheckbox(app.UIFigure);
            app.usefirstsugnificantNDCheckBox.Text = 'use first sugnificant ND';
            app.intensitiestocheckEditFieldLabel = uilabel(app.UIFigure);
            app.intensitiestocheckEditFieldLabel.Text = 'intensities to check';
            app.intensitiestocheckEditField = uieditfield(app.UIFigure, 'numeric');
            app.intensitiestocheckEditField.Value = 4;
            app.multiplybySTDscoreCheckBox = uicheckbox(app.UIFigure);
            app.multiplybySTDscoreCheckBox.Text = 'multiply by STD score ';
            app.RegularizationValueEditFieldLabel = uilabel(app.UIFigure);
            app.RegularizationValueEditFieldLabel.Text = 'RegularizationValue';
            app.RegularizationValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.RegularizationValueEditField.Value = 0.01;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = clustring3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end